classdef GUI_Video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout9                  matlab.ui.container.GridLayout
        PorcentajeN                  matlab.ui.control.NumericEditField
        MissedFramesLabel            matlab.ui.control.Label
        MissedFramesN                matlab.ui.control.NumericEditField
        FramesperdidosLabel          matlab.ui.control.Label
        GridLayout8                  matlab.ui.container.GridLayout
        allMasksAngulo               matlab.ui.control.NumericEditField
        AllMasksAngleLabel           matlab.ui.control.Label
        PorcentajeV                  matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        MissedFramesV                matlab.ui.control.NumericEditField
        FramesperdidosLabel_2        matlab.ui.control.Label
        GridLayout7                  matlab.ui.container.GridLayout
        PorcentajeA                  matlab.ui.control.NumericEditField
        MissedFramesLabel_2          matlab.ui.control.Label
        MissedFramesA                matlab.ui.control.NumericEditField
        FramesperdidosLabel_3        matlab.ui.control.Label
        GridLayout6                  matlab.ui.container.GridLayout
        PorcentajeEditField          matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        FramestotalesEditField       matlab.ui.control.NumericEditField
        FramestotalesEditFieldLabel  matlab.ui.control.Label
        InicioButton                 matlab.ui.control.Button
        FrameNaranja                 matlab.ui.control.UIAxes
        AllMasks                     matlab.ui.control.UIAxes
        FrameVerde                   matlab.ui.control.UIAxes
        FrameAmarillo                matlab.ui.control.UIAxes
        Frame                        matlab.ui.control.UIAxes
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        BienvenidoaSRSLabel          matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)

            v = VideoReader('videoMarcadores.mp4'); 
           
            j=0;
            k=0;
            l=0;

            % revisar for
            for i=1:v.NumFrames
                tic; % revisar si esto está bien
                app.PorcentajeEditField.Value=(i/v.NumFrames)*100;
                app.FramestotalesEditField.Value=i;
                tic; % rev

                f=readFrame(v); % num frames, v está en el instante x
                [BN,mN] = createMaskN(f); % naranja
                [BV,mV] = createMaskV(f); % verde
                [BA,mA] = createMaskA(f); % amarillo

                BV_ao = bwareaopen(BV,200);
                BA_ao = bwareaopen(BA,250);

                se=strel('disk',20); % disk tamaño probar con 50 y 20

                BN_cl = imclose(BN,se);
                BV_cl = imclose(BV_ao,se);
                BA_cl = imclose(BA_ao,se);

                %mascara aplicada en colorTotal
                mN = bsxfun(@times,f,cast(BN_cl,'like',f));
                mV = bsxfun(@times,f,cast(BV_cl,'like',f));
                mA = bsxfun(@times,f,cast(BA_cl,'like',f));

                imshow(f,'Parent',app.Frame);
                imshow( mN,'Parent',app.FrameNaranja);
                imshow( mV,'Parent',app.FrameVerde);
                imshow( mA,'Parent',app.FrameAmarillo);

                framesN=sum(BN_cl,'all');
                framesA=sum(BA_cl,'all');
                framesV=sum(BV_cl,'all');

                if(framesA==0)
                    j=j+1;
                    app.MissedFramesA.Value=j;
                end

                app.PorcentajeA.Value=(app.MissedFramesA.Value/v.NumFrames)*100;

                if(framesV==0)
                    k=k+1;
                    app.MissedFramesV.Value=k;
                end
                app.PorcentajeV.Value=(app.MissedFramesV.Value/v.NumFrames)*100;

                if(framesN==0)
                    l=l+1;
                    app.MissedFramesN.Value=l;
                end
                app.PorcentajeN.Value=(app.MissedFramesN.Value/v.NumFrames)*100;

                % para mostrar todas las mascaras
                %mAll = mN |mV | mA;
                mAll= BN_cl | BA_cl | BV_cl;
                mAllc=bsxfun(@times,f,cast(mAll,'like',f));
                imshow(mAllc,'Parent',app.AllMasks);
                drawnow;

                [labeledImage, numberOfRegions] = bwlabel(mAll);
                % centroides
                if numberOfRegions >= 1
                    stats =regionprops(labeledImage, 'BoundingBox', 'Centroid');
                    for r=1 : numberOfRegions
                        thisCentroid=stats(r).Centroid;
                        hold(app.AllMasks,'on')
                        plot(app.AllMasks,thisCentroid(1),thisCentroid(2),'r+','MarkerSize',10,'LineWidth',2);
                        drawnow;
                        hold(app.AllMasks,'off');
                        m(r,:)=stats(r).Centroid;
                    end
                end

                if numberOfRegions==3
                    [mi, in]=min(m(:,2));
                    cN=m(in,:);
                    m(in,:)=[];
                    [mi, in]=min(m(:,2));
                    cA=m(in,:);
                    m(in,:)=[];
                    cV=m;


                    p=polyfit([cN(1) cA(1)],[cN(2) cA(2)],1);
                    p2=polyfit([cA(1) cV(1)],[cA(2) cV(2)],1);

                    vect1=[1 p(1)];
                    vect2=[1 p2(1)];

                    dp=dot(vect1,vect2);
                    length1=sqrt(sum(vect1.^2));
                    length2=sqrt(sum(vect2.^2));
                    angle = 180-acos(dp/(length1*length2))*180/pi;
                    app.allMasksAngulo.Value=angle;

                else
                    app.allMasksAngulo.Value=0;
                end
                drawnow;
                t = toc;
                pause(1-t);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'foto.png';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Frame = uiaxes(app.GridLayout4);
            title(app.Frame, 'Frame')
            app.Frame.XTick = [];
            app.Frame.XTickLabel = '';
            app.Frame.YTick = [];
            app.Frame.YTickLabel = '';
            app.Frame.Tag = 'Frame';
            app.FrameAmarillo = uiaxes(app.GridLayout4);
            title(app.FrameAmarillo, 'Amarillo')
            app.FrameAmarillo.XTick = [];
            app.FrameAmarillo.YTick = [];
            app.FrameAmarillo.Tag = 'FrameAmarillo';
            app.FrameVerde = uiaxes(app.GridLayout4);
            title(app.FrameVerde, 'Verde')
            app.FrameVerde.XTick = [];
            app.FrameVerde.XTickLabel = '';
            app.FrameVerde.YTick = [];
            app.FrameVerde.YTickLabel = '';
            app.FrameVerde.Tag = 'FrameVerde';
            app.AllMasks = uiaxes(app.GridLayout4);
            title(app.AllMasks, 'Marcadores')
            app.AllMasks.XTick = [];
            app.AllMasks.XTickLabel = '';
            app.AllMasks.YTick = [];
            app.AllMasks.YTickLabel = '';
            app.AllMasks.Tag = 'AllMasks';
            app.FrameNaranja = uiaxes(app.GridLayout4);
            title(app.FrameNaranja, 'Naranja')
            app.FrameNaranja.XTick = [];
            app.FrameNaranja.YTick = [];
            app.FrameNaranja.Tag = 'FrameNaranja';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.InicioButton = uibutton(app.GridLayout6, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Tag = 'GoButton';
            app.InicioButton.Text = 'Inicio';
            app.FramestotalesEditFieldLabel = uilabel(app.GridLayout6);
            app.FramestotalesEditFieldLabel.Tag = 'TotalFrames';
            app.FramestotalesEditFieldLabel.Text = 'Frames totales';
            app.FramestotalesEditField = uieditfield(app.GridLayout6, 'numeric');
            app.FramestotalesEditField.Tag = 'TotalFramesEditField';
            app.Label = uilabel(app.GridLayout6);
            app.Label.Tag = 'Porcentaje';
            app.Label.Text = '%';
            app.PorcentajeEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PorcentajeEditField.ValueDisplayFormat = '%.2f';
            app.PorcentajeEditField.Tag = 'PorcentajeEditField';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.FramesperdidosLabel_3 = uilabel(app.GridLayout7);
            app.FramesperdidosLabel_3.Text = 'Frames perdidos';
            app.MissedFramesA = uieditfield(app.GridLayout7, 'numeric');
            app.MissedFramesA.Tag = 'MissedFramesA';
            app.MissedFramesLabel_2 = uilabel(app.GridLayout7);
            app.MissedFramesLabel_2.Text = '% perdido';
            app.PorcentajeA = uieditfield(app.GridLayout7, 'numeric');
            app.PorcentajeA.ValueDisplayFormat = '%.2f';
            app.PorcentajeA.Tag = 'PorcentajeA';
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.FramesperdidosLabel_2 = uilabel(app.GridLayout8);
            app.FramesperdidosLabel_2.Text = 'Frames perdidos';
            app.MissedFramesV = uieditfield(app.GridLayout8, 'numeric');
            app.MissedFramesV.Tag = 'MissedFramesV';
            app.Label_2 = uilabel(app.GridLayout8);
            app.Label_2.Tag = 'PorcentajeV';
            app.Label_2.Text = '% perdido';
            app.PorcentajeV = uieditfield(app.GridLayout8, 'numeric');
            app.PorcentajeV.ValueDisplayFormat = '%.2f';
            app.PorcentajeV.Tag = 'PorcentajeV';
            app.AllMasksAngleLabel = uilabel(app.GridLayout8);
            app.AllMasksAngleLabel.Tag = 'allMasksAngulo';
            app.AllMasksAngleLabel.Text = 'Ángulo del tobillo';
            app.allMasksAngulo = uieditfield(app.GridLayout8, 'numeric');
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.FramesperdidosLabel = uilabel(app.GridLayout9);
            app.FramesperdidosLabel.Text = 'Frames perdidos';
            app.MissedFramesN = uieditfield(app.GridLayout9, 'numeric');
            app.MissedFramesN.Tag = 'MissedFramesN';
            app.MissedFramesLabel = uilabel(app.GridLayout9);
            app.MissedFramesLabel.Text = '% perdido';
            app.PorcentajeN = uieditfield(app.GridLayout9, 'numeric');
            app.PorcentajeN.ValueDisplayFormat = '%.2f';
            app.PorcentajeN.Tag = 'PorcentajeN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Video

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end