classdef GUI_avatarAlfa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        BienvenidoaSRSLabel   matlab.ui.control.Label
        ImageLogo             matlab.ui.control.Image
        GridLayout2           matlab.ui.container.GridLayout
        VisualizarmovimientosButtonGroup  matlab.ui.container.ButtonGroup
        PosicininicialButton  matlab.ui.control.Button
        BajadaButton          matlab.ui.control.Button
        SubidaButton          matlab.ui.control.Button
        GridLayout6           matlab.ui.container.GridLayout
        FechaLabel2           matlab.ui.control.Label
        PesoLabel2            matlab.ui.control.Label
        EdadLabel2            matlab.ui.control.Label
        PacienteLabel2        matlab.ui.control.Label
        FechaLabel            matlab.ui.control.Label
        PesokgLabel           matlab.ui.control.Label
        EdadLabel             matlab.ui.control.Label
        PacienteLabel         matlab.ui.control.Label
        GridLayout4           matlab.ui.container.GridLayout
        GrficasButton         matlab.ui.control.Button
        InformeButton         matlab.ui.control.Button
        AxesAvatar            matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global nombre apellido edad peso;

            %load datos.mat nombre apellido edad peso;

            app.PacienteLabel2.Text = strcat(nombre," ",apellido);
            app.EdadLabel2.Text = int2str(edad);
            app.PesoLabel2.Text = num2str(peso);
            
            formato = 'dd/mm/yy';
            app.FechaLabel2.Text = datestr(now,formato);
        end

        % Button pushed function: SubidaButton
        function SubidaButtonPushed(app, event)
            f = [1,2,3,4; 5,6,7,8; 5,6,2,1; 8,7,3,4; 6,7,3,2; 5,8,4,1];
color = [1,0,0; 0,1,0; 0,0,1; 1,1,0; 1,0,1; 0,1,1];
%--------------------------------------------------------------------------
%PIE
pieDTalon=[0,0,0;20,0,0;20,15,0;0,15,0;0,0,10;20,0,10;20,15,10;0,15,10];
pieDPunta=[20,0,0;35,0,0;40,15,0;20,15,0;20,0,10;35,0,5;40,15,5;20,15,10];
dedoGordo=[35,10,0;45,10,0;45,15,0;40,15,0;35,10,5;45,10,5;45,15,5;40,15,5];
dedoIndice=[35,7,0;43,7,0;43,10,0;35,10,0;35,7,5;43,7,5;43,10,5;35,10,5];
dedoCorazon=[35,4,0;42,4,0;42,7,0;35,7,0;35,4,5;42,4,5;42,7,5;35,7,5];
dedoAnular=[35,2,0;41,2,0;41,4,0;35,4,0;35,2,5;41,2,5;41,4,5;35,4,5];
dedoMenique=[35,0,0;38,0,0;38,2,0;35,2,0;35,0,5;38,0,5;38,2,5;35,2,5];
%-----------------------------------------------------------------------------
%PIERNA INFERIOR
Pierna1=[5,0,10;15,0,10;15,15,10;5,15,10;5,0,70;15,0,70;15,15,70;5,15,70];

Pierna2=[0,0,10;5,0,10;5,15,10;0,15,10;-5,0,45;5,0,45;5,15,45;-5,15,45];

Pierna3=[-5,0,45;5,0,45;5,15,45;-5,15,45;0,0,70;5,0,70;5,15,70;0,15,70];

%-----------------------------------------------------------------------------
%PIERNA SUPERIOR
piernaSuperior1=[0,0,70;15,0,70;15,15,70;0,15,70;-10,-5,120;15,-5,120;15,20,120;-10,20,120];

piernaSuperior2=[-10,-5,120;15,-5,120;15,20,120;-10,20,120;-10,-5,140;15,-5,140;15,20,140;-10,20,140];

% Definicion de partes del cuerpo (lado izquierdo)
torso1 = [-10,-5,140;15,-5,140;15,55,140;-10,55,140;-10,-10,250;15,-10,250;15,60,250;-10,60,250];
torso= [-10,-5,140;2,-5,140;2,55,140;-10,55,140;-10,-5,150;2,-5,150;2,55,150;-10,55,150];

        Rtorso = rotationMatrix(0,0,0);
        Rcadera = rotationMatrix(0,0,0);
        Rrodilla = rotationMatrix(0,0,0);
        Rtobillo = rotationMatrix(0,0,0);


% Angulos de cadera en subida
y_cs = [-8,-9,-11,-12,-10,-9,-6,-5,-4,-4,-3,-0,-15,-12,-15,-10,-3,-6,-0,-0,-28];

% Angulos de rodilla en subida
y_rs = [6,10,20,26,28,27,26,27,35,45,55,55,75,87,93,87,65,50,30,10,6];

% Angulos de tobillo en subida
y_ts = [-20,-10,5,7,10,12,13,15,19,22,24,20,10,2,1,-2,-10,-15,-20,-26,-20];


fm =10;
% Movimiento progresivo de subida
for ii = 1:size(y_cs,2)
    Rtorso = rotationMatrix(0,0,0);
    
cla(app.AxesAvatar);
tic
    torsoRotado = applyRotationNx3(torso,sum(torso)/8,Rtorso);
    torsoRotado1 = applyRotationNx3(torso1,sum(torso)/8,Rtorso);

    patch(app.AxesAvatar,'faces',f,'vertices',torsoRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.AxesAvatar,'faces',f,'vertices',torsoRotado1,'FaceVertexCData',color,'FaceColor','flat');

    Rcadera = rotationMatrix(0,y_cs(ii),0);

    piernaSuperiorRotada1 = applyRotationNx3(applyRotationNx3(piernaSuperior1,...
        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
    piernaSuperiorRotada2 = applyRotationNx3(applyRotationNx3(piernaSuperior2,...
        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

    patch(app.AxesAvatar,'faces',f,'vertices',piernaSuperiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.AxesAvatar,'faces',f,'vertices',piernaSuperiorRotada2,'FaceVertexCData',color,'FaceColor','flat');

%         for jj = 1:size(y_rs,2)
            Rrodilla = rotationMatrix(0,y_rs(ii),0);
            
            piernaInferiorRotada1 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna1,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
            piernaInferiorRotada2 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna2,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
            piernaInferiorRotada3 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna3,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

            patch(app.AxesAvatar,'faces',f,'vertices',piernaInferiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.AxesAvatar,'faces',f,'vertices',piernaInferiorRotada2,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.AxesAvatar,'faces',f,'vertices',piernaInferiorRotada3,'FaceVertexCData',color,'FaceColor','flat');
               
%                 for kk = 1:size(y_ts,2)
                    Rtobillo = rotationMatrix(0,y_ts(ii),0);

                    pieTalonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDTalon,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    piePuntaRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDPunta,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoPulgarRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoGordo,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoIndiceRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoIndice,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoCorazonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoCorazon,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoAnularRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoAnular,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoMeniqueRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoMenique,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

                    patch(app.AxesAvatar,'faces',f,'vertices',pieTalonRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',piePuntaRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',dedoPulgarRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',dedoIndiceRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',dedoCorazonRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',dedoAnularRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',dedoMeniqueRotado,'FaceVertexCData',color,'FaceColor','flat');
                    
                   
                    tiempo = toc;
                pause(1/fm-tiempo);
                    drawnow;
end
        end

        % Button pushed function: BajadaButton
        function BajadaButtonPushed(app, event)
                  f = [1,2,3,4; 5,6,7,8; 5,6,2,1; 8,7,3,4; 6,7,3,2; 5,8,4,1];
color = [1,0,0; 0,1,0; 0,0,1; 1,1,0; 1,0,1; 0,1,1];
%--------------------------------------------------------------------------
%PIE
pieDTalon=[0,0,0;20,0,0;20,15,0;0,15,0;0,0,10;20,0,10;20,15,10;0,15,10];
pieDPunta=[20,0,0;35,0,0;40,15,0;20,15,0;20,0,10;35,0,5;40,15,5;20,15,10];
dedoGordo=[35,10,0;45,10,0;45,15,0;40,15,0;35,10,5;45,10,5;45,15,5;40,15,5];
dedoIndice=[35,7,0;43,7,0;43,10,0;35,10,0;35,7,5;43,7,5;43,10,5;35,10,5];
dedoCorazon=[35,4,0;42,4,0;42,7,0;35,7,0;35,4,5;42,4,5;42,7,5;35,7,5];
dedoAnular=[35,2,0;41,2,0;41,4,0;35,4,0;35,2,5;41,2,5;41,4,5;35,4,5];
dedoMenique=[35,0,0;38,0,0;38,2,0;35,2,0;35,0,5;38,0,5;38,2,5;35,2,5];
%-----------------------------------------------------------------------------
%PIERNA INFERIOR
Pierna1=[5,0,10;15,0,10;15,15,10;5,15,10;5,0,70;15,0,70;15,15,70;5,15,70];

Pierna2=[0,0,10;5,0,10;5,15,10;0,15,10;-5,0,45;5,0,45;5,15,45;-5,15,45];

Pierna3=[-5,0,45;5,0,45;5,15,45;-5,15,45;0,0,70;5,0,70;5,15,70;0,15,70];

%-----------------------------------------------------------------------------
%PIERNA SUPERIOR
piernaSuperior1=[0,0,70;15,0,70;15,15,70;0,15,70;-10,-5,120;15,-5,120;15,20,120;-10,20,120];

piernaSuperior2=[-10,-5,120;15,-5,120;15,20,120;-10,20,120;-10,-5,140;15,-5,140;15,20,140;-10,20,140];

% Definicion de partes del cuerpo (lado izquierdo)
torso1 = [-10,-5,140;15,-5,140;15,55,140;-10,55,140;-10,-10,250;15,-10,250;15,60,250;-10,60,250];
torso= [-10,-5,140;2,-5,140;2,55,140;-10,55,140;-10,-5,150;2,-5,150;2,55,150;-10,55,150];

        Rtorso = rotationMatrix(0,0,0);
        Rcadera = rotationMatrix(0,0,0);
        Rrodilla = rotationMatrix(0,0,0);
        Rtobillo = rotationMatrix(0,0,0);


% Angulos de cadera en bajada
y_cb = [46,40,35,30,25,15,10,5,2,-2,-4,-6,-5,0,10,23,34,45,47,49,46];
% Angulos de rodilla en bajada
y_rb = [60,58,56,53,45,35,26,22,12,8,7,7,10,23,45,65,85,94,90,75,60];
% Angulos de tobillo en bajada
y_tb = [11,10,13,14,11,8,7,6,7,9,10,14,19,25,29,31,29,25,20,15,11];

fm =10;
% Movimiento progresivo de subida
for ii = 1:size(y_cb,2)
    Rtorso = rotationMatrix(0,0,0);
    
cla(app.AxesAvatar);
tic
    torsoRotado = applyRotationNx3(torso,sum(torso)/8,Rtorso);
    torsoRotado1 = applyRotationNx3(torso1,sum(torso)/8,Rtorso);

    patch(app.AxesAvatar,'faces',f,'vertices',torsoRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.AxesAvatar,'faces',f,'vertices',torsoRotado1,'FaceVertexCData',color,'FaceColor','flat');

    Rcadera = rotationMatrix(0,y_cb(ii),0);

    piernaSuperiorRotada1 = applyRotationNx3(applyRotationNx3(piernaSuperior1,...
        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
    piernaSuperiorRotada2 = applyRotationNx3(applyRotationNx3(piernaSuperior2,...
        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

    patch(app.AxesAvatar,'faces',f,'vertices',piernaSuperiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.AxesAvatar,'faces',f,'vertices',piernaSuperiorRotada2,'FaceVertexCData',color,'FaceColor','flat');

%         for jj = 1:size(y_rs,2)
            Rrodilla = rotationMatrix(0,y_rb(ii),0);
            
            piernaInferiorRotada1 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna1,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
            piernaInferiorRotada2 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna2,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
            piernaInferiorRotada3 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna3,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

            patch(app.AxesAvatar,'faces',f,'vertices',piernaInferiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.AxesAvatar,'faces',f,'vertices',piernaInferiorRotada2,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.AxesAvatar,'faces',f,'vertices',piernaInferiorRotada3,'FaceVertexCData',color,'FaceColor','flat');
               
%                 for kk = 1:size(y_ts,2)
                    Rtobillo = rotationMatrix(0,y_tb(ii),0);

                    pieTalonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDTalon,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    piePuntaRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDPunta,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoPulgarRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoGordo,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoIndiceRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoIndice,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoCorazonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoCorazon,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoAnularRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoAnular,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoMeniqueRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoMenique,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

                    patch(app.AxesAvatar,'faces',f,'vertices',pieTalonRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',piePuntaRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',dedoPulgarRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',dedoIndiceRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',dedoCorazonRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',dedoAnularRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.AxesAvatar,'faces',f,'vertices',dedoMeniqueRotado,'FaceVertexCData',color,'FaceColor','flat');
                    
                    
                    tiempo = toc;
                pause(1/fm-tiempo);
                    drawnow;
end      
        end

        % Button pushed function: PosicininicialButton
        function PosicionInicialPushed(app, event)
                              f = [1,2,3,4; 5,6,7,8; 5,6,2,1; 8,7,3,4; 6,7,3,2; 5,8,4,1];
color = [1,0,0; 0,1,0; 0,0,1; 1,1,0; 1,0,1; 0,1,1];
%--------------------------------------------------------------------------
%PIE
cla(app.AxesAvatar);
pieDTalon=[0,0,0;20,0,0;20,15,0;0,15,0;0,0,10;20,0,10;20,15,10;0,15,10];
patch(app.AxesAvatar,'faces',f,'vertices',pieDTalon,'FaceVertexCData',color,'FaceColor','flat');
pieDPunta=[20,0,0;35,0,0;40,15,0;20,15,0;20,0,10;35,0,5;40,15,5;20,15,10];
patch(app.AxesAvatar,'faces',f,'vertices',pieDPunta,'FaceVertexCData',color,'FaceColor','flat');
dedoGordo=[35,10,0;45,10,0;45,15,0;40,15,0;35,10,5;45,10,5;45,15,5;40,15,5];
patch(app.AxesAvatar,'faces',f,'vertices',dedoGordo,'FaceVertexCData',color,'FaceColor','flat');
dedoIndice=[35,7,0;43,7,0;43,10,0;35,10,0;35,7,5;43,7,5;43,10,5;35,10,5];
patch(app.AxesAvatar,'faces',f,'vertices',dedoIndice,'FaceVertexCData',color,'FaceColor','flat');
dedoCorazon=[35,4,0;42,4,0;42,7,0;35,7,0;35,4,5;42,4,5;42,7,5;35,7,5];
patch(app.AxesAvatar,'faces',f,'vertices',dedoCorazon,'FaceVertexCData',color,'FaceColor','flat');
dedoAnular=[35,2,0;41,2,0;41,4,0;35,4,0;35,2,5;41,2,5;41,4,5;35,4,5];
patch(app.AxesAvatar,'faces',f,'vertices',dedoAnular,'FaceVertexCData',color,'FaceColor','flat');
dedoMenique=[35,0,0;38,0,0;38,2,0;35,2,0;35,0,5;38,0,5;38,2,5;35,2,5];
patch(app.AxesAvatar,'faces',f,'vertices',dedoMenique,'FaceVertexCData',color,'FaceColor','flat');
%-----------------------------------------------------------------------------
%PIERNA INFERIOR
Pierna1=[5,0,10;15,0,10;15,15,10;5,15,10;5,0,70;15,0,70;15,15,70;5,15,70];
patch(app.AxesAvatar,'faces',f,'vertices',Pierna1,'FaceVertexCData',color,'FaceColor','flat');
Pierna2=[0,0,10;5,0,10;5,15,10;0,15,10;-5,0,45;5,0,45;5,15,45;-5,15,45];
patch(app.AxesAvatar,'faces',f,'vertices',Pierna2,'FaceVertexCData',color,'FaceColor','flat');
Pierna3=[-5,0,45;5,0,45;5,15,45;-5,15,45;0,0,70;5,0,70;5,15,70;0,15,70];
patch(app.AxesAvatar,'faces',f,'vertices',Pierna3,'FaceVertexCData',color,'FaceColor','flat');
%-----------------------------------------------------------------------------
%PIERNA SUPERIOR
piernaSuperior1=[0,0,70;15,0,70;15,15,70;0,15,70;-10,-5,120;15,-5,120;15,20,120;-10,20,120];
patch(app.AxesAvatar,'faces',f,'vertices',piernaSuperior1,'FaceVertexCData',color,'FaceColor','flat');
piernaSuperior2=[-10,-5,120;15,-5,120;15,20,120;-10,20,120;-10,-5,140;15,-5,140;15,20,140;-10,20,140];
patch(app.AxesAvatar,'faces',f,'vertices',piernaSuperior2,'FaceVertexCData',color,'FaceColor','flat');
% Definicion de partes del cuerpo (lado izquierdo)
torso1 = [-10,-5,140;15,-5,140;15,55,140;-10,55,140;-10,-10,250;15,-10,250;15,60,250;-10,60,250];
patch(app.AxesAvatar,'faces',f,'vertices',torso1,'FaceVertexCData',color,'FaceColor','flat');
torso= [-10,-5,140;2,-5,140;2,55,140;-10,55,140;-10,-5,150;2,-5,150;2,55,150;-10,55,150];
patch(app.AxesAvatar,'faces',f,'vertices',torso,'FaceVertexCData',color,'FaceColor','flat');


        end

        % Button pushed function: GrficasButton
        function GraficasPushed(app, event)
            global nombre apellido edad peso;
            GUI_graficas;
            app.delete;
        end

        % Button pushed function: InformeButton
        function InformePushed(app, event)
            global nombre apellido edad peso;
            GUI_informe;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AxesAvatar = uiaxes(app.GridLayout);
            title(app.AxesAvatar, 'Simulación')
            xlabel(app.AxesAvatar, 'X')
            ylabel(app.AxesAvatar, 'Y')
            zlabel(app.AxesAvatar, 'Z')
            app.AxesAvatar.XLim = [-130 100];
            app.AxesAvatar.YLim = [-10 60];
            app.AxesAvatar.ZLim = [-20 250];
            app.AxesAvatar.Tag = 'axesAvatar';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformeButton = uibutton(app.GridLayout4, 'push');
            app.InformeButton.ButtonPushedFcn = createCallbackFcn(app, @InformePushed, true);
            app.InformeButton.Tag = 'buttonInforme';
            app.InformeButton.Text = 'Informe';
            app.GrficasButton = uibutton(app.GridLayout4, 'push');
            app.GrficasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficasPushed, true);
            app.GrficasButton.Tag = 'buttonGráficas';
            app.GrficasButton.Text = 'Gráficas';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.PacienteLabel = uilabel(app.GridLayout6);
            app.PacienteLabel.Tag = 'labelPaciente';
            app.PacienteLabel.Text = 'Paciente:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesokgLabel = uilabel(app.GridLayout6);
            app.PesokgLabel.Tag = 'labelPeso';
            app.PesokgLabel.Text = 'Peso (kg):';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.PacienteLabel2 = uilabel(app.GridLayout6);
            app.PacienteLabel2.Tag = 'labelPaciente2';
            app.PacienteLabel2.Text = '';
            app.EdadLabel2 = uilabel(app.GridLayout6);
            app.EdadLabel2.Tag = 'labelEdad2';
            app.EdadLabel2.Text = '';
            app.PesoLabel2 = uilabel(app.GridLayout6);
            app.PesoLabel2.Tag = 'labelPeso2';
            app.PesoLabel2.Text = '';
            app.FechaLabel2 = uilabel(app.GridLayout6);
            app.FechaLabel2.Tag = 'labelFecha2';
            app.FechaLabel2.Text = '';
            app.VisualizarmovimientosButtonGroup = uibuttongroup(app.GridLayout2);
            app.VisualizarmovimientosButtonGroup.Title = 'Visualizar movimientos';
            app.SubidaButton = uibutton(app.VisualizarmovimientosButtonGroup, 'push');
            app.SubidaButton.ButtonPushedFcn = createCallbackFcn(app, @SubidaButtonPushed, true);
            app.SubidaButton.Tag = 'buttonSubida';
            app.SubidaButton.Text = 'Subida';
            app.BajadaButton = uibutton(app.VisualizarmovimientosButtonGroup, 'push');
            app.BajadaButton.ButtonPushedFcn = createCallbackFcn(app, @BajadaButtonPushed, true);
            app.BajadaButton.Tag = 'buttonBajada';
            app.BajadaButton.Text = 'Bajada';
            app.PosicininicialButton = uibutton(app.VisualizarmovimientosButtonGroup, 'push');
            app.PosicininicialButton.ButtonPushedFcn = createCallbackFcn(app, @PosicionInicialPushed, true);
            app.PosicininicialButton.Tag = 'buttonPosInicial';
            app.PosicininicialButton.Text = 'Posición inicial';
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'foto.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_avatarAlfa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end