classdef GUI_graficas_new4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        TobilloLabel                    matlab.ui.control.Label
        RodillaLabel                    matlab.ui.control.Label
        CaderaLabel                     matlab.ui.control.Label
        LeyendaLabel                    matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GraficasConjuntasTab            matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        MovimientoPacienteConjuntasLabel  matlab.ui.control.Label
        MovimientoIdealConjuntasLabel   matlab.ui.control.Label
        BajadaIdealConjuntasAxes        matlab.ui.control.UIAxes
        SubidaPacienteConjuntasAxes     matlab.ui.control.UIAxes
        BajadaPacienteConjuntasAxes     matlab.ui.control.UIAxes
        SubidaIdealConjuntasAxes        matlab.ui.control.UIAxes
        GraficasCaderaTab               matlab.ui.container.Tab
        GridLayout7_2                   matlab.ui.container.GridLayout
        MovimientoPacienteCaderaLabel   matlab.ui.control.Label
        MovimientoIdealCaderaLabel      matlab.ui.control.Label
        BajadaIdealCaderaAxes           matlab.ui.control.UIAxes
        SubidaPacienteCaderaAxes        matlab.ui.control.UIAxes
        BajadaPacienteCaderaAxes        matlab.ui.control.UIAxes
        SubidaIdealCaderaAxes           matlab.ui.control.UIAxes
        GraficasRodillaTab              matlab.ui.container.Tab
        GridLayout7_3                   matlab.ui.container.GridLayout
        MovimientoPacienteRodillaLabel  matlab.ui.control.Label
        MovimientoIdealRodillaLabel     matlab.ui.control.Label
        BajadaIdealRodillaAxes          matlab.ui.control.UIAxes
        SubidaPacienteRodillaAxes       matlab.ui.control.UIAxes
        BajadaPacienteRodillaAxes       matlab.ui.control.UIAxes
        SubidaIdealRodillaAxes          matlab.ui.control.UIAxes
        GraficasTobilloTab              matlab.ui.container.Tab
        GridLayout7_4                   matlab.ui.container.GridLayout
        MovimientoPacienteTobilloLabel  matlab.ui.control.Label
        MovimientoIdealTobilloLabel     matlab.ui.control.Label
        BajadaIdealTobilloAxes          matlab.ui.control.UIAxes
        SubidaPacienteTobilloAxes       matlab.ui.control.UIAxes
        BajadaPacienteTobilloAxes       matlab.ui.control.UIAxes
        SubidaIdealTobilloAxes          matlab.ui.control.UIAxes
        GridLayout3                     matlab.ui.container.GridLayout
        BienvenidoaSRSLabel             matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        AvatarButton                    matlab.ui.control.Button
        InformeButton                   matlab.ui.control.Button
        GridLayout7_5                   matlab.ui.container.GridLayout
        InformacionCapturaButton        matlab.ui.control.Button
        IniciarButton                   matlab.ui.control.Button
        SubidaBajadaSwitch              matlab.ui.control.Switch
        BorrarButton                    matlab.ui.control.Button
        PausarButton                    matlab.ui.control.Button
        GraficasLabel                   matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        ApellidosLabel2                 matlab.ui.control.Label
        ApellidosLabel                  matlab.ui.control.Label
        FechaLabel2                     matlab.ui.control.Label
        PesoLabel2                      matlab.ui.control.Label
        EdadLabel2                      matlab.ui.control.Label
        NombreLabel2                    matlab.ui.control.Label
        FechaLabel                      matlab.ui.control.Label
        PesoLabel                       matlab.ui.control.Label
        EdadLabel                       matlab.ui.control.Label
        NombreLabel                     matlab.ui.control.Label
        ImageLogo                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        movimiento = "subida";
        condicion = 0;
        conjuntas = 0;
        cadera = 0;
        rodilla = 0;
        tobillo = 0;

        sensor_cadera_subida_app = [];
        sensor_rodilla_subida_app = [];
        sensor_tobillo_subida_app = [];

        sensor_cadera_bajada_app = [];
        sensor_rodilla_bajada_app = [];
        sensor_tobillo_bajada_app = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global nombre apellidos edad peso tiempoRealizado ...
                   caderaSMIN caderaSMAX caderaBMIN caderaBMAX ...
                   rodillaSMIN rodillaSMAX rodillaBMIN rodillaBMAX ...
                   tobilloSMIN tobilloSMAX tobilloBMIN tobilloBMAX ...
                   s_cs s_rs s_ts s_cb s_rb s_tb;

            s_cs = app.sensor_cadera_subida_app';
            s_rs = app.sensor_rodilla_subida_app';
            s_ts = app.sensor_tobillo_subida_app';

            s_cb = app.sensor_cadera_bajada_app';
            s_rb = app.sensor_rodilla_bajada_app';
            s_tb = app.sensor_tobillo_bajada_app';
           
            %load datos.mat nombre apellido edad peso;

            app.NombreLabel2.Text = strcat(nombre);
            app.ApellidosLabel2.Text = strcat(apellidos);
            app.EdadLabel2.Text = strcat(int2str(edad), " años");
            app.PesoLabel2.Text = strcat(num2str(peso) , "kg");

            formato = 'dd/mm/yy';
            app.FechaLabel2.Text = datestr(now,formato);

            app.GraficasConjuntas;
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            global nombre apellidos edad peso tiempoRealizado ...
                   caderaSMIN caderaSMAX caderaBMIN caderaBMAX ...
                   rodillaSMIN rodillaSMAX rodillaBMIN rodillaBMAX ...
                   tobilloSMIN tobilloSMAX tobilloBMIN tobilloBMAX ...
                   s_cs s_rs s_ts s_cb s_rb s_tb;

            s_cs = app.sensor_cadera_subida_app';
            s_rs = app.sensor_rodilla_subida_app';
            s_ts = app.sensor_tobillo_subida_app';

            s_cb = app.sensor_cadera_bajada_app';
            s_rb = app.sensor_rodilla_bajada_app';
            s_tb = app.sensor_tobillo_bajada_app';

            GUI_avatarAlfa_new;
            app.delete;
        end

        % Button pushed function: InformeButton
        function InformeButtonPushed(app, event)
            global nombre apellidos edad peso tiempoRealizado ...
                   caderaSMIN caderaSMAX caderaBMIN caderaBMAX ...
                   rodillaSMIN rodillaSMAX rodillaBMIN rodillaBMAX ...
                   tobilloSMIN tobilloSMAX tobilloBMIN tobilloBMAX ...
                   s_cs s_rs s_ts s_cb s_rb s_tb;

            s_cs = app.sensor_cadera_subida_app';
            s_rs = app.sensor_rodilla_subida_app';
            s_ts = app.sensor_tobillo_subida_app';

            s_cb = app.sensor_cadera_bajada_app';
            s_rb = app.sensor_rodilla_bajada_app';
            s_tb = app.sensor_tobillo_bajada_app';

            GUI_informe_new;
            app.delete;
        end

        % Button pushed function: IniciarButton
        function Iniciar(app, event)
            RPY2;
            app.condicion = 1;
           
            if strcmp(app.movimiento,'subida')

                app.sensor_cadera_subida_app = sensor_cadera;
                app.sensor_rodilla_subida_app = sensor_rodilla;
                app.sensor_tobillo_subida_app = sensor_tobillo;

                while app.TabGroup.SelectedTab == app.GraficasConjuntasTab % Iniciar subida conjuntas
                    app.conjuntas = 1;
                    app.GraficasConjuntas;
                end

                while app.TabGroup.SelectedTab == app.GraficasCaderaTab % Iniciar subida cadera
                    app.cadera = 1;
                    app.GraficasCadera;
                end

                while app.TabGroup.SelectedTab == app.GraficasRodillaTab % Iniciar subida rodilla
                    app.rodilla = 1;
                    app.GraficasRodilla;
                end

                while app.TabGroup.SelectedTab == app.GraficasTobilloTab % Iniciar subida tobillo
                    app.tobillo = 1;
                    app.GraficasTobillo;
                end

            elseif strcmp(app.movimiento,'bajada')

                app.sensor_cadera_bajada_app = sensor_cadera;
                app.sensor_rodilla_bajada_app = sensor_rodilla;
                app.sensor_tobillo_bajada_app = sensor_tobillo;

                while app.TabGroup.SelectedTab == app.GraficasConjuntasTab % Iniciar bajada conjuntas
                    app.conjuntas = 2;
                    app.GraficasConjuntas;
                end
                
                while app.TabGroup.SelectedTab == app.GraficasCaderaTab % Iniciar bajada cadera
                    app.cadera = 2;
                    app.GraficasCadera;
                end

                while app.TabGroup.SelectedTab == app.GraficasRodillaTab % Iniciar bajada rodilla
                    app.rodilla = 2;
                    app.GraficasRodilla;
                end

                while app.TabGroup.SelectedTab == app.GraficasTobilloTab % Iniciar bajada tobillo
                    app.tobillo = 2;
                    app.GraficasTobillo;
                end
            end
        end

        % Button pushed function: PausarButton
        function Pausar(app, event)
            app.condicion = 2;
            pause();
        end

        % Button pushed function: BorrarButton
        function Borrar(app, event)
            app.condicion = 3;

            if strcmp(app.movimiento,'subida')
                while app.TabGroup.SelectedTab == app.GraficasConjuntasTab % Borrar subida conjuntas
                    app.conjuntas = 3;
                    app.GraficasConjuntas;
                end

                while app.TabGroup.SelectedTab == app. GraficasCaderaTab % Borrar subida cadera
                    app.cadera = 3;
                    app.GraficasCadera;
                end

                while app.TabGroup.SelectedTab == app.GraficasRodillaTab % Borrar subida rodilla
                    app.rodilla = 3;
                    app.GraficasRodilla;
                end

                while app.TabGroup.SelectedTab == app.GraficasTobilloTab % Borrar subida tobillo
                    app.tobillo = 3;
                    app.GraficasTobillo;
                end

            elseif strcmp(app.movimiento,'bajada')
                while app.TabGroup.SelectedTab == app.GraficasConjuntasTab % Borrar bajada conjuntas
                    app.conjuntas = 4;
                    app.GraficasConjuntas;
                end
                
                while app.TabGroup.SelectedTab == app.GraficasCaderaTab % Borrar bajada cadera
                    app.cadera = 4;
                    app.GraficasCadera;
                end

                while app.TabGroup.SelectedTab == app.GraficasRodillaTab % Borrar bajada rodilla
                    app.rodilla = 4;
                    app.GraficasRodilla;
                end

                while app.TabGroup.SelectedTab == app.GraficasTobilloTab % Borrar bajada tobillo
                    app.tobillo = 4;
                    app.GraficasTobillo;
                end
            end
        end

        % Button pushed function: InformacionCapturaButton
        function InformacionCaptura(app, event)
            global caderaSMIN caderaSMAX caderaBMIN caderaBMAX ...
                   rodillaSMIN rodillaSMAX rodillaBMIN rodillaBMAX ...
                   tobilloSMIN tobilloSMAX tobilloBMIN tobilloBMAX ...
                   s_cs s_rs s_ts s_cb s_rb s_tb;

            s_cs = app.sensor_cadera_subida_app';
            s_rs = app.sensor_rodilla_subida_app';
            s_ts = app.sensor_tobillo_subida_app';

            s_cb = app.sensor_cadera_bajada_app';
            s_rb = app.sensor_rodilla_bajada_app';
            s_tb = app.sensor_tobillo_bajada_app';

            GUI_informacionCaptura_new2;
        end

        % Button down function: BajadaIdealConjuntasAxes, 
        % BajadaPacienteConjuntasAxes, GraficasConjuntasTab, 
        % SubidaIdealConjuntasAxes, SubidaPacienteCaderaAxes, 
        % SubidaPacienteConjuntasAxes
        function GraficasConjuntas(app, event)
            main;
            
            hold(app.SubidaIdealConjuntasAxes,"on");
            plot(app.SubidaIdealConjuntasAxes,ts_ideal,'b');
            plot(app.SubidaIdealConjuntasAxes,rs_ideal,'g');
            plot(app.SubidaIdealConjuntasAxes,cs_ideal,'r');
            hold(app.SubidaIdealConjuntasAxes,"off");
           
            hold(app.BajadaIdealConjuntasAxes,"on");
            plot(app.BajadaIdealConjuntasAxes,tb_ideal,'b');
            plot(app.BajadaIdealConjuntasAxes,rb_ideal,'g');
            plot(app.BajadaIdealConjuntasAxes,cb_ideal,'r');
            hold(app.BajadaIdealConjuntasAxes,"off");

            if app.conjuntas == 1 % Pintar subida
                hold(app.SubidaPacienteConjuntasAxes,"on");
                plot(app.SubidaPacienteConjuntasAxes,app.sensor_cadera_subida_app,'r');
                plot(app.SubidaPacienteConjuntasAxes,app.sensor_rodilla_subida_app,'g');
                plot(app.SubidaPacienteConjuntasAxes,app.sensor_tobillo_subida_app,'b');
                hold(app.SubidaPacienteConjuntasAxes,"off");

                drawnow;
                disp("Estoy en conjuntas subida");
                disp(app.sensor_cadera_subida_app);

            elseif app.conjuntas == 2 % Pintar bajada
                hold(app.BajadaPacienteConjuntasAxes,"on");
                plot(app.BajadaPacienteConjuntasAxes,app.sensor_cadera_bajada_app,'r');
                plot(app.BajadaPacienteConjuntasAxes,app.sensor_rodilla_bajada_app,'g');
                plot(app.BajadaPacienteConjuntasAxes,app.sensor_tobillo_bajada_app,'b');
                hold(app.BajadaPacienteConjuntasAxes,"off");
                
                drawnow;
                disp("Estoy en conjuntas bajada");
                disp(app.sensor_cadera_bajada_app);

            elseif app.conjuntas == 3 % Borrar subida
                cla(app.SubidaPacienteConjuntasAxes);
                drawnow;

            elseif app.conjuntas == 4 % Borrar bajada
                cla(app.BajadaPacienteConjuntasAxes);
                drawnow;

            end

        end

        % Button down function: BajadaIdealCaderaAxes, 
        % BajadaPacienteCaderaAxes, GraficasCaderaTab, 
        % SubidaIdealCaderaAxes
        function GraficasCadera(app, event)
            main;

            plot(app.SubidaIdealCaderaAxes,cs_ideal,'r');
            plot(app.BajadaIdealCaderaAxes,cb_ideal,'r');

            if app.cadera == 1 % Pintar subida
                plot(app.BajadaPacienteCaderaAxes,app.sensor_cadera_bajada_app,'r');

                hold(app.SubidaPacienteCaderaAxes,"on");
                plot(app.SubidaPacienteCaderaAxes,app.sensor_cadera_subida_app,'r');
                hold(app.SubidaPacienteCaderaAxes,"off");

                drawnow;
                disp("Estoy en cadera subida");
                disp(app.sensor_cadera_subida_app);

            elseif app.cadera == 2 % Pintar bajada
                plot(app.SubidaPacienteCaderaAxes,app.sensor_cadera_subida_app,'r');

                hold(app.BajadaPacienteCaderaAxes,"on");
                plot(app.BajadaPacienteCaderaAxes,app.sensor_cadera_bajada_app,'r');
                hold(app.BajadaPacienteCaderaAxes,"off");

                drawnow;
                disp("Estoy en cadera bajada");
                disp(app.sensor_cadera_bajada_app);

            elseif app.cadera == 3 % Borrar subida
                cla(app.SubidaPacienteCaderaAxes);
                drawnow;
                
            elseif app.cadera == 4 % Borrar bajada
                cla(app.BajadaPacienteCaderaAxes);
                drawnow;

            end
           
        end

        % Button down function: BajadaIdealRodillaAxes, 
        % BajadaPacienteRodillaAxes, GraficasRodillaTab, 
        % SubidaIdealRodillaAxes, SubidaPacienteRodillaAxes
        function GraficasRodilla(app, event)
            main;

            plot(app.SubidaIdealRodillaAxes,rs_ideal,'g');
            plot(app.BajadaIdealRodillaAxes,rb_ideal,'g');

            if app.rodilla == 1 % Pintar subida
                plot(app.BajadaPacienteRodillaAxes,app.sensor_rodilla_bajada_app,'g');
                
                hold(app.SubidaPacienteRodillaAxes,"on");
                plot(app.SubidaPacienteRodillaAxes,app.sensor_rodilla_subida_app,'g');
                hold(app.SubidaPacienteRodillaAxes,"off");

                drawnow;
                disp("Estoy en rodilla subida");
                disp(app.sensor_rodilla_subida_app);

            elseif app.rodilla == 2 % Pintar bajada
                plot(app.SubidaPacienteRodillaAxes,app.sensor_rodilla_subida_app,'g');

                hold(app.BajadaPacienteRodillaAxes,"on");
                plot(app.BajadaPacienteRodillaAxes,app.sensor_rodilla_bajada_app,'g');
                hold(app.BajadaPacienteRodillaAxes,"off");
                
                drawnow;
                disp("Estoy en rodilla bajada");
                disp(app.sensor_rodilla_bajada_app);

            elseif app.rodilla == 3 % Borrar subida
                cla(app.SubidaPacienteRodillaAxes);
                drawnow;
                
            elseif app.rodilla == 4 % Borrar bajada
                cla(app.BajadaPacienteRodillaAxes);
                drawnow;

            end

        end

        % Button down function: GraficasTobilloTab
        function GraficasTobillo(app, event)
            main;

            plot(app.SubidaIdealTobilloAxes,ts_ideal,'b');
            plot(app.BajadaIdealTobilloAxes,tb_ideal,'b');

            if app.tobillo == 1 % Pintar subida
                plot(app.BajadaPacienteTobilloAxes,app.sensor_tobillo_bajada_app,'b');

                hold(app.SubidaPacienteTobilloAxes,"on");
                plot(app.SubidaPacienteTobilloAxes,app.sensor_tobillo_subida_app,'b');
                hold(app.SubidaPacienteTobilloAxes,"off");
            
                drawnow;
                disp("Estoy en tobillo subida");
                disp(app.sensor_tobillo_subida_app);
                
            elseif app.tobillo == 2 % Pintar bajada
                plot(app.SubidaPacienteTobilloAxes,app.sensor_tobillo_subida_app,'b');

                hold(app.BajadaPacienteTobilloAxes,"on");
                plot(app.BajadaPacienteTobilloAxes,app.sensor_tobillo_bajada_app,'b');
                hold(app.BajadaPacienteTobilloAxes,"off");

                drawnow;
                disp("Estoy en tobillo bajada");
                disp(app.sensor_tobillo_bajada_app);

            elseif app.tobillo == 3 % Borrar subida
                cla(app.SubidaPacienteTobilloAxes);
                drawnow;
                
            elseif app.tobillo == 4 % Borrar bajada
                cla(app.BajadaPacienteTobilloAxes);
                drawnow;

            end

        end

        % Value changed function: SubidaBajadaSwitch
        function SwitchSubidaBajada(app, event)
           s1 = 'Subida';
           s2 = 'Bajada';

            if strcmp(app.SubidaBajadaSwitch.Value,s1)
                app.movimiento = "subida";
            elseif strcmp(app.SubidaBajadaSwitch.Value,s2)
                app.movimiento = "bajada";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'foto.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.NombreLabel = uilabel(app.GridLayout6);
            app.NombreLabel.Tag = 'labelNombre';
            app.NombreLabel.Text = 'Nombre:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesoLabel = uilabel(app.GridLayout6);
            app.PesoLabel.Tag = 'labelPeso';
            app.PesoLabel.Text = 'Peso:';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.NombreLabel2 = uilabel(app.GridLayout6);
            app.NombreLabel2.Tag = 'labelNombre2';
            app.NombreLabel2.Text = '';
            app.EdadLabel2 = uilabel(app.GridLayout6);
            app.EdadLabel2.Tag = 'labelEdad2';
            app.EdadLabel2.Text = '';
            app.PesoLabel2 = uilabel(app.GridLayout6);
            app.PesoLabel2.Tag = 'labelPeso2';
            app.PesoLabel2.Text = '';
            app.FechaLabel2 = uilabel(app.GridLayout6);
            app.FechaLabel2.Tag = 'labelFecha2';
            app.FechaLabel2.Text = '';
            app.ApellidosLabel = uilabel(app.GridLayout6);
            app.ApellidosLabel.Tag = 'labelApellidos';
            app.ApellidosLabel.Text = 'Apellidos:';
            app.ApellidosLabel2 = uilabel(app.GridLayout6);
            app.ApellidosLabel2.Tag = 'labelApellidos2';
            app.ApellidosLabel2.Text = '';
            app.GridLayout7_5 = uigridlayout(app.GridLayout2);
            app.GraficasLabel = uilabel(app.GridLayout7_5);
            app.GraficasLabel.Tag = 'labelCapturaMovimiento';
            app.GraficasLabel.Text = 'Captura de movimiento';
            app.PausarButton = uibutton(app.GridLayout7_5, 'push');
            app.PausarButton.ButtonPushedFcn = createCallbackFcn(app, @Pausar, true);
            app.PausarButton.Tag = 'buttonPausar';
            app.PausarButton.Text = 'Pausar';
            app.BorrarButton = uibutton(app.GridLayout7_5, 'push');
            app.BorrarButton.ButtonPushedFcn = createCallbackFcn(app, @Borrar, true);
            app.BorrarButton.Tag = 'buttonBorrar';
            app.BorrarButton.Text = 'Borrar';
            app.SubidaBajadaSwitch = uiswitch(app.GridLayout7_5, 'slider');
            app.SubidaBajadaSwitch.Items = {'Subida', 'Bajada'};
            app.SubidaBajadaSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchSubidaBajada, true);
            app.SubidaBajadaSwitch.Tag = 'switchSubidaBajada';
            app.SubidaBajadaSwitch.Value = 'Subida';
            app.IniciarButton = uibutton(app.GridLayout7_5, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @Iniciar, true);
            app.IniciarButton.Tag = 'buttonIniciar';
            app.IniciarButton.Text = 'Iniciar';
            app.InformacionCapturaButton = uibutton(app.GridLayout7_5, 'push');
            app.InformacionCapturaButton.ButtonPushedFcn = createCallbackFcn(app, @InformacionCaptura, true);
            app.InformacionCapturaButton.Tag = 'buttonInformacionCaptura';
            app.InformacionCapturaButton.Text = 'Información de la captura';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformeButton = uibutton(app.GridLayout4, 'push');
            app.InformeButton.ButtonPushedFcn = createCallbackFcn(app, @InformeButtonPushed, true);
            app.InformeButton.Tag = 'buttonInforme';
            app.InformeButton.Text = 'Informe';
            app.AvatarButton = uibutton(app.GridLayout4, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GraficasConjuntasTab = uitab(app.TabGroup);
            app.GraficasConjuntasTab.Title = 'Gráficas conjuntas';
            app.GraficasConjuntasTab.ButtonDownFcn = createCallbackFcn(app, @GraficasConjuntas, true);
            app.GraficasConjuntasTab.Tag = 'tabGraficasConjuntas';
            app.GridLayout7 = uigridlayout(app.GraficasConjuntasTab);
            app.SubidaIdealConjuntasAxes = uiaxes(app.GridLayout7);
            title(app.SubidaIdealConjuntasAxes, 'Subida')
            xlabel(app.SubidaIdealConjuntasAxes, 'Tiempo (ms)')
            ylabel(app.SubidaIdealConjuntasAxes, 'Ángulo (º)')
            app.SubidaIdealConjuntasAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasConjuntas, true);
            app.SubidaIdealConjuntasAxes.Tag = 'axesSubidaIdealConjuntas';
            app.BajadaPacienteConjuntasAxes = uiaxes(app.GridLayout7);
            title(app.BajadaPacienteConjuntasAxes, 'Bajada')
            xlabel(app.BajadaPacienteConjuntasAxes, 'Tiempo (ms)')
            ylabel(app.BajadaPacienteConjuntasAxes, 'Ángulo (º)')
            app.BajadaPacienteConjuntasAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasConjuntas, true);
            app.BajadaPacienteConjuntasAxes.Tag = 'axesBajadaPacienteConjuntas';
            app.SubidaPacienteConjuntasAxes = uiaxes(app.GridLayout7);
            title(app.SubidaPacienteConjuntasAxes, 'Subida')
            xlabel(app.SubidaPacienteConjuntasAxes, 'Tiempo (ms)')
            ylabel(app.SubidaPacienteConjuntasAxes, 'Ángulo (º)')
            app.SubidaPacienteConjuntasAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasConjuntas, true);
            app.SubidaPacienteConjuntasAxes.Tag = 'axesSubidaPacienteConjuntas';
            app.BajadaIdealConjuntasAxes = uiaxes(app.GridLayout7);
            title(app.BajadaIdealConjuntasAxes, 'Bajada')
            xlabel(app.BajadaIdealConjuntasAxes, 'Tiempo (ms)')
            ylabel(app.BajadaIdealConjuntasAxes, 'Ángulo (º)')
            app.BajadaIdealConjuntasAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasConjuntas, true);
            app.BajadaIdealConjuntasAxes.Tag = 'axesBajadaIdealConjuntas';
            app.MovimientoIdealConjuntasLabel = uilabel(app.GridLayout7);
            app.MovimientoIdealConjuntasLabel.Tag = 'labelMovimientoIdealConjuntas';
            app.MovimientoIdealConjuntasLabel.Text = 'Movimiento ideal';
            app.MovimientoPacienteConjuntasLabel = uilabel(app.GridLayout7);
            app.MovimientoPacienteConjuntasLabel.Tag = 'labelMovimientoPacienteConjuntas';
            app.MovimientoPacienteConjuntasLabel.Text = 'Movimiento del paciente';
            app.GraficasCaderaTab = uitab(app.TabGroup);
            app.GraficasCaderaTab.Title = 'Gráficas cadera';
            app.GraficasCaderaTab.ButtonDownFcn = createCallbackFcn(app, @GraficasCadera, true);
            app.GraficasCaderaTab.Tag = 'tabGraficasCadera';
            app.GridLayout7_2 = uigridlayout(app.GraficasCaderaTab);
            app.SubidaIdealCaderaAxes = uiaxes(app.GridLayout7_2);
            title(app.SubidaIdealCaderaAxes, 'Subida')
            xlabel(app.SubidaIdealCaderaAxes, 'Tiempo (ms)')
            ylabel(app.SubidaIdealCaderaAxes, 'Ángulo (º)')
            app.SubidaIdealCaderaAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasCadera, true);
            app.SubidaIdealCaderaAxes.Tag = 'axesSubidaIdealCadera';
            app.BajadaPacienteCaderaAxes = uiaxes(app.GridLayout7_2);
            title(app.BajadaPacienteCaderaAxes, 'Bajada')
            xlabel(app.BajadaPacienteCaderaAxes, 'Tiempo (ms)')
            ylabel(app.BajadaPacienteCaderaAxes, 'Ángulo (º)')
            app.BajadaPacienteCaderaAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasCadera, true);
            app.BajadaPacienteCaderaAxes.Tag = 'axesBajadaPacienteCadera';
            app.SubidaPacienteCaderaAxes = uiaxes(app.GridLayout7_2);
            title(app.SubidaPacienteCaderaAxes, 'Subida')
            xlabel(app.SubidaPacienteCaderaAxes, 'Tiempo (ms)')
            ylabel(app.SubidaPacienteCaderaAxes, 'Ángulo (º)')
            app.SubidaPacienteCaderaAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasConjuntas, true);
            app.SubidaPacienteCaderaAxes.Tag = 'axesSubidaPacienteCadera';
            app.BajadaIdealCaderaAxes = uiaxes(app.GridLayout7_2);
            title(app.BajadaIdealCaderaAxes, 'Bajada')
            xlabel(app.BajadaIdealCaderaAxes, 'Tiempo (ms)')
            ylabel(app.BajadaIdealCaderaAxes, 'Ángulo (º)')
            app.BajadaIdealCaderaAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasCadera, true);
            app.BajadaIdealCaderaAxes.Tag = 'axesBajadaIdealCadera';
            app.MovimientoIdealCaderaLabel = uilabel(app.GridLayout7_2);
            app.MovimientoIdealCaderaLabel.Tag = 'labelMovimientoIdealCadera';
            app.MovimientoIdealCaderaLabel.Text = 'Movimiento ideal';
            app.MovimientoPacienteCaderaLabel = uilabel(app.GridLayout7_2);
            app.MovimientoPacienteCaderaLabel.Tag = 'labelMovimientoPacienteCadera';
            app.MovimientoPacienteCaderaLabel.Text = 'Movimiento del paciente';
            app.GraficasRodillaTab = uitab(app.TabGroup);
            app.GraficasRodillaTab.Title = 'Gráficas rodilla';
            app.GraficasRodillaTab.ButtonDownFcn = createCallbackFcn(app, @GraficasRodilla, true);
            app.GraficasRodillaTab.Tag = 'tabGraficasRodilla';
            app.GridLayout7_3 = uigridlayout(app.GraficasRodillaTab);
            app.SubidaIdealRodillaAxes = uiaxes(app.GridLayout7_3);
            title(app.SubidaIdealRodillaAxes, 'Subida')
            xlabel(app.SubidaIdealRodillaAxes, 'Tiempo (ms)')
            ylabel(app.SubidaIdealRodillaAxes, 'Ángulo (º)')
            app.SubidaIdealRodillaAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasRodilla, true);
            app.SubidaIdealRodillaAxes.Tag = 'axesSubidaIdealRodilla';
            app.BajadaPacienteRodillaAxes = uiaxes(app.GridLayout7_3);
            title(app.BajadaPacienteRodillaAxes, 'Bajada')
            xlabel(app.BajadaPacienteRodillaAxes, 'Tiempo (ms)')
            ylabel(app.BajadaPacienteRodillaAxes, 'Ángulo (º)')
            app.BajadaPacienteRodillaAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasRodilla, true);
            app.BajadaPacienteRodillaAxes.Tag = 'axesBajadaPacienteRodilla';
            app.SubidaPacienteRodillaAxes = uiaxes(app.GridLayout7_3);
            title(app.SubidaPacienteRodillaAxes, 'Subida')
            xlabel(app.SubidaPacienteRodillaAxes, 'Tiempo (ms)')
            ylabel(app.SubidaPacienteRodillaAxes, 'Ángulo (º)')
            app.SubidaPacienteRodillaAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasRodilla, true);
            app.SubidaPacienteRodillaAxes.Tag = 'axesSubidaPacienteRodilla';
            app.BajadaIdealRodillaAxes = uiaxes(app.GridLayout7_3);
            title(app.BajadaIdealRodillaAxes, 'Bajada')
            xlabel(app.BajadaIdealRodillaAxes, 'Tiempo (ms)')
            ylabel(app.BajadaIdealRodillaAxes, 'Ángulo (º)')
            app.BajadaIdealRodillaAxes.ButtonDownFcn = createCallbackFcn(app, @GraficasRodilla, true);
            app.BajadaIdealRodillaAxes.Tag = 'axesBajadaIdealRodilla';
            app.MovimientoIdealRodillaLabel = uilabel(app.GridLayout7_3);
            app.MovimientoIdealRodillaLabel.Tag = 'labelMovimientoIdealRodilla';
            app.MovimientoIdealRodillaLabel.Text = 'Movimiento ideal';
            app.MovimientoPacienteRodillaLabel = uilabel(app.GridLayout7_3);
            app.MovimientoPacienteRodillaLabel.Tag = 'labelMovimientoPacienteRodilla';
            app.MovimientoPacienteRodillaLabel.Text = 'Movimiento del paciente';
            app.GraficasTobilloTab = uitab(app.TabGroup);
            app.GraficasTobilloTab.Title = 'Gráficas tobillo';
            app.GraficasTobilloTab.ButtonDownFcn = createCallbackFcn(app, @GraficasTobillo, true);
            app.GraficasTobilloTab.Tag = 'tabGraficasTobillo';
            app.GridLayout7_4 = uigridlayout(app.GraficasTobilloTab);
            app.SubidaIdealTobilloAxes = uiaxes(app.GridLayout7_4);
            title(app.SubidaIdealTobilloAxes, 'Subida')
            xlabel(app.SubidaIdealTobilloAxes, 'Tiempo (ms)')
            ylabel(app.SubidaIdealTobilloAxes, 'Ángulo (º)')
            app.SubidaIdealTobilloAxes.Tag = 'axesSubidaIdealTobillo';
            app.BajadaPacienteTobilloAxes = uiaxes(app.GridLayout7_4);
            title(app.BajadaPacienteTobilloAxes, 'Bajada')
            xlabel(app.BajadaPacienteTobilloAxes, 'Tiempo (ms)')
            ylabel(app.BajadaPacienteTobilloAxes, 'Ángulo (º)')
            app.BajadaPacienteTobilloAxes.Tag = 'axesBajadaPacienteTobillo';
            app.SubidaPacienteTobilloAxes = uiaxes(app.GridLayout7_4);
            title(app.SubidaPacienteTobilloAxes, 'Subida')
            xlabel(app.SubidaPacienteTobilloAxes, 'Tiempo (ms)')
            ylabel(app.SubidaPacienteTobilloAxes, 'Ángulo (º)')
            app.SubidaPacienteTobilloAxes.Tag = 'axesSubidaPacienteTobillo';
            app.BajadaIdealTobilloAxes = uiaxes(app.GridLayout7_4);
            title(app.BajadaIdealTobilloAxes, 'Bajada')
            xlabel(app.BajadaIdealTobilloAxes, 'Tiempo (ms)')
            ylabel(app.BajadaIdealTobilloAxes, 'Ángulo (º)')
            app.BajadaIdealTobilloAxes.Tag = 'axesBajadaIdealTobillo';
            app.MovimientoIdealTobilloLabel = uilabel(app.GridLayout7_4);
            app.MovimientoIdealTobilloLabel.Tag = 'labelMovimientoIdealTobillo';
            app.MovimientoIdealTobilloLabel.Text = 'Movimiento ideal';
            app.MovimientoPacienteTobilloLabel = uilabel(app.GridLayout7_4);
            app.MovimientoPacienteTobilloLabel.Tag = 'labelMovimientoPacienteTobillo';
            app.MovimientoPacienteTobilloLabel.Text = 'Movimiento del paciente';
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.LeyendaLabel = uilabel(app.GridLayout8);
            app.LeyendaLabel.Tag = 'labeLeyenda';
            app.LeyendaLabel.Text = 'Leyenda:';
            app.CaderaLabel = uilabel(app.GridLayout8);
            app.CaderaLabel.Tag = 'labelLeyendaCadera';
            app.CaderaLabel.Text = 'Cadera';
            app.RodillaLabel = uilabel(app.GridLayout8);
            app.RodillaLabel.Tag = 'labelLeyendaRodilla';
            app.RodillaLabel.Text = 'Rodilla';
            app.TobilloLabel = uilabel(app.GridLayout8);
            app.TobilloLabel.Tag = 'labelLeyendaTobillo';
            app.TobilloLabel.Text = 'Tobillo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_graficas_new4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end