classdef GUI_informacionCaptura_new2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        CaderaSTab                     matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        GridLayout10                   matlab.ui.container.GridLayout
        GraveCaderaSubidaLabel         matlab.ui.control.Label
        LeveCaderaSubidaLabel          matlab.ui.control.Label
        NormalCaderaSubidaLabel        matlab.ui.control.Label
        IndiceVariacionCaderaSubidaLabel  matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        DesviacionCaderaSubidaLabel2   matlab.ui.control.Label
        DesviacionCaderaSubidaLabel    matlab.ui.control.Label
        CaderaBTab                     matlab.ui.container.Tab
        GridLayout3_7                  matlab.ui.container.GridLayout
        GridLayout8_2                  matlab.ui.container.GridLayout
        GridLayout10_2                 matlab.ui.container.GridLayout
        GraveCaderaBajadaLabel         matlab.ui.control.Label
        LeveCaderaBajadaLabel          matlab.ui.control.Label
        NormalCaderaBajadaLabel        matlab.ui.control.Label
        IndiceVariacionCaderaBajadaLabel  matlab.ui.control.Label
        GridLayout7_2                  matlab.ui.container.GridLayout
        DesviacionCaderaBajadaLabel2   matlab.ui.control.Label
        DesviacionCaderaBajadaLabel    matlab.ui.control.Label
        RodillaSTab                    matlab.ui.container.Tab
        GridLayout3_8                  matlab.ui.container.GridLayout
        GridLayout8_3                  matlab.ui.container.GridLayout
        GridLayout10_3                 matlab.ui.container.GridLayout
        GraveRodillaSubidaLabel        matlab.ui.control.Label
        LeveRodillaSubidaLabel         matlab.ui.control.Label
        NormalRodillaSubidaLabel       matlab.ui.control.Label
        IndiceVariacionRodillaSubidaLabel  matlab.ui.control.Label
        GridLayout7_3                  matlab.ui.container.GridLayout
        DesviacionRodillaSubidaLabel2  matlab.ui.control.Label
        DesviacionRodillaSubidaLabel   matlab.ui.control.Label
        RodillaBTab                    matlab.ui.container.Tab
        GridLayout3_9                  matlab.ui.container.GridLayout
        GridLayout8_4                  matlab.ui.container.GridLayout
        GridLayout10_4                 matlab.ui.container.GridLayout
        GraveRodillaBajadaLabel        matlab.ui.control.Label
        LeveRodillaBajadaLabel         matlab.ui.control.Label
        NormalRodillaBajadaLabel       matlab.ui.control.Label
        IndiceVariacionRodillaBajadaLabel  matlab.ui.control.Label
        GridLayout7_4                  matlab.ui.container.GridLayout
        DesviacionRodillaBajadaLabel2  matlab.ui.control.Label
        DesviacionRodillaBajadaLabel   matlab.ui.control.Label
        TobilloSTab                    matlab.ui.container.Tab
        GridLayout3_10                 matlab.ui.container.GridLayout
        GridLayout8_5                  matlab.ui.container.GridLayout
        GridLayout10_5                 matlab.ui.container.GridLayout
        GraveTobilloSubidaLabel        matlab.ui.control.Label
        LeveTobilloSubidaLabel         matlab.ui.control.Label
        NormalTobilloSubidaLabel       matlab.ui.control.Label
        IndiceVariacionTobilloSubidaLabel  matlab.ui.control.Label
        GridLayout7_5                  matlab.ui.container.GridLayout
        DesviacionTobilloSubidaLabel2  matlab.ui.control.Label
        DesviacionTobilloSubidaLabel   matlab.ui.control.Label
        TobilloBTab                    matlab.ui.container.Tab
        GridLayout3_11                 matlab.ui.container.GridLayout
        GridLayout8_6                  matlab.ui.container.GridLayout
        GridLayout10_6                 matlab.ui.container.GridLayout
        GraveTobilloBajadaLabel        matlab.ui.control.Label
        LeveTobilloBajadaLabel         matlab.ui.control.Label
        NormalTobilloBajadaLabel       matlab.ui.control.Label
        IndiceVariacionTobilloBajadaLabel  matlab.ui.control.Label
        GridLayout7_6                  matlab.ui.container.GridLayout
        DesviacionTobilloBajadaLabel2  matlab.ui.control.Label
        DesviacionTobilloBajadaLabel   matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global caderaSMIN caderaSMAX caderaBMIN caderaBMAX ...
                   rodillaSMIN rodillaSMAX rodillaBMIN rodillaBMAX ...
                   tobilloSMIN tobilloSMAX tobilloBMIN tobilloBMAX ...
                   s_cs s_rs s_ts s_cb s_rb s_tb;
            
            desviacion_cs = mean(s_cs);
            desviacion_rs = mean(s_rs);
            desviacion_ts = mean(s_ts);
            desviacion_cb = mean(s_cb);
            desviacion_rb = mean(s_rb);
            desviacion_tb = mean(s_tb);
            
            % Cadera
            app.DesviacionCaderaSubidaLabel2.Text = strcat(num2str(abs(desviacion_cs)), "º");
            
            if abs(desviacion_cs) < 2.12 
                app.DesviacionCaderaSubidaLabel2.BackgroundColor = [0.6902 1 0.6118];
            elseif abs(desviacion_cs) >= 2.12 && abs(desviacion_cs) <= 6.28
                app.DesviacionCaderaSubidaLabel2.BackgroundColor = [1 0.9882 0.6118];
            elseif abs(desviacion_cs) > 6.28
                app.DesviacionCaderaSubidaLabel2.BackgroundColor = [1 0.6118 0.6196];
            end 

            app.DesviacionCaderaBajadaLabel2.Text = strcat(num2str(abs(desviacion_cb)), "º");
            
            if abs(desviacion_cb) < 2.30 
                app.DesviacionCaderaBajadaLabel2.BackgroundColor = [0.6902 1 0.6118];
            elseif abs(desviacion_cb) >= 2.30 && abs(desviacion_cb) <= 4.86
                app.DesviacionCaderaBajadaLabel2.BackgroundColor = [1 0.9882 0.6118];
            elseif abs(desviacion_cb) > 4.86
                app.DesviacionCaderaBajadaLabel2.BackgroundColor = [1 0.6118 0.6196];
            end 

            % Rodilla
            app.DesviacionRodillaSubidaLabel2.Text = strcat(num2str(abs(desviacion_rs)), "º");
            
            if abs(desviacion_rs) < 2.96 
                app.DesviacionRodillaSubidaLabel2.BackgroundColor = [0.6902 1 0.6118];
            elseif abs(desviacion_rs) >= 2.96 && abs(desviacion_rs) <= 12.22
                app.DesviacionRodillaSubidaLabel2.BackgroundColor = [1 0.9882 0.6118];
            elseif abs(desviacion_rs) > 12.22
                app.DesviacionRodillaSubidaLabel2.BackgroundColor = [1 0.6118 0.6196];
            end 

            app.DesviacionRodillaBajadaLabel2.Text = strcat(num2str(abs(desviacion_rb)), "º");
            
            if abs(desviacion_rb) < 2.55 
                app.DesviacionRodillaBajadaLabel2.BackgroundColor = [0.6902 1 0.6118];
            elseif abs(desviacion_rb) >= 2.55 && abs(desviacion_rb) <= 11.18
                app.DesviacionRodillaBajadaLabel2.BackgroundColor = [1 0.9882 0.6118];
            elseif abs(desviacion_rb) > 11.18
                app.DesviacionRodillaBajadaLabel2.BackgroundColor = [1 0.6118 0.6196];
            end

            % Tobillo
            app.DesviacionTobilloSubidaLabel2.Text = strcat(num2str(abs(desviacion_ts)), "º");
            
            if abs(desviacion_ts) < 4.57 
                app.DesviacionTobilloSubidaLabel2.BackgroundColor = [0.6902 1 0.6118];
            elseif abs(desviacion_ts) >= 4.57 && abs(desviacion_ts) <= 8.70
                app.DesviacionTobilloSubidaLabel2.BackgroundColor = [1 0.9882 0.6118];
            elseif abs(desviacion_ts) > 8.70
                app.DesviacionTobilloSubidaLabel2.BackgroundColor = [1 0.6118 0.6196];
            end 
            
            app.DesviacionTobilloBajadaLabel2.Text = strcat(num2str(abs(desviacion_tb)), "º");
            
            if abs(desviacion_tb) < 3.73 
                app.DesviacionTobilloBajadaLabel2.BackgroundColor = [0.6902 1 0.6118];
            elseif abs(desviacion_tb) >= 3.73 && abs(desviacion_tb) <= 11.10
                app.DesviacionTobilloBajadaLabel2.BackgroundColor = [1 0.9882 0.6118];
            elseif abs(desviacion_tb) > 11.10
                app.DesviacionTobilloBajadaLabel2.BackgroundColor = [1 0.6118 0.6196];
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'foto.png';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.CaderaSTab = uitab(app.TabGroup);
            app.CaderaSTab.Title = 'CaderaS';
            app.GridLayout3 = uigridlayout(app.CaderaSTab);
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.DesviacionCaderaSubidaLabel = uilabel(app.GridLayout7);
            app.DesviacionCaderaSubidaLabel.Tag = 'labelDesviacionCaderaSubida';
            app.DesviacionCaderaSubidaLabel.Text = 'Desviación de la cadera en la subida';
            app.DesviacionCaderaSubidaLabel2 = uilabel(app.GridLayout7);
            app.DesviacionCaderaSubidaLabel2.Tag = 'labelDesviacionCaderaSubida2';
            app.DesviacionCaderaSubidaLabel2.Text = '';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.IndiceVariacionCaderaSubidaLabel = uilabel(app.GridLayout8);
            app.IndiceVariacionCaderaSubidaLabel.Tag = 'labelIndiceVariacionCaderaSubida';
            app.IndiceVariacionCaderaSubidaLabel.Text = 'Indice de variación';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.NormalCaderaSubidaLabel = uilabel(app.GridLayout10);
            app.NormalCaderaSubidaLabel.Tag = 'labelNormalCaderaSubida';
            app.NormalCaderaSubidaLabel.Text = '  < 2.12º normal';
            app.LeveCaderaSubidaLabel = uilabel(app.GridLayout10);
            app.LeveCaderaSubidaLabel.Tag = 'labelLeveCaderaSubida';
            app.LeveCaderaSubidaLabel.Text = ' 2.12º - 6.28º leve ';
            app.GraveCaderaSubidaLabel = uilabel(app.GridLayout10);
            app.GraveCaderaSubidaLabel.Tag = 'labelGraveCaderaSubida';
            app.GraveCaderaSubidaLabel.Text = ' > 6.28º grave ';
            app.CaderaBTab = uitab(app.TabGroup);
            app.CaderaBTab.Title = 'CaderaB';
            app.GridLayout3_7 = uigridlayout(app.CaderaBTab);
            app.GridLayout7_2 = uigridlayout(app.GridLayout3_7);
            app.DesviacionCaderaBajadaLabel = uilabel(app.GridLayout7_2);
            app.DesviacionCaderaBajadaLabel.Tag = 'labelDesviacionCaderaBajada';
            app.DesviacionCaderaBajadaLabel.Text = 'Desviación de la cadera en la bajada';
            app.DesviacionCaderaBajadaLabel2 = uilabel(app.GridLayout7_2);
            app.DesviacionCaderaBajadaLabel2.Tag = 'labelDesviacionCaderaBajada2';
            app.DesviacionCaderaBajadaLabel2.Text = '';
            app.GridLayout8_2 = uigridlayout(app.GridLayout3_7);
            app.IndiceVariacionCaderaBajadaLabel = uilabel(app.GridLayout8_2);
            app.IndiceVariacionCaderaBajadaLabel.Tag = 'labelIndiceVariacionCaderaBajada';
            app.IndiceVariacionCaderaBajadaLabel.Text = 'Indice de variación';
            app.GridLayout10_2 = uigridlayout(app.GridLayout8_2);
            app.NormalCaderaBajadaLabel = uilabel(app.GridLayout10_2);
            app.NormalCaderaBajadaLabel.Tag = 'labelNormalCaderaBajada';
            app.NormalCaderaBajadaLabel.Text = '  < 2.30º normal';
            app.LeveCaderaBajadaLabel = uilabel(app.GridLayout10_2);
            app.LeveCaderaBajadaLabel.Tag = 'labelLeveCaderaBajada';
            app.LeveCaderaBajadaLabel.Text = ' 2.30º - 4.86º leve ';
            app.GraveCaderaBajadaLabel = uilabel(app.GridLayout10_2);
            app.GraveCaderaBajadaLabel.Tag = 'labelGraveCaderaBajada';
            app.GraveCaderaBajadaLabel.Text = ' > 4.86º grave ';
            app.RodillaSTab = uitab(app.TabGroup);
            app.RodillaSTab.Title = 'RodillaS';
            app.GridLayout3_8 = uigridlayout(app.RodillaSTab);
            app.GridLayout7_3 = uigridlayout(app.GridLayout3_8);
            app.DesviacionRodillaSubidaLabel = uilabel(app.GridLayout7_3);
            app.DesviacionRodillaSubidaLabel.Tag = 'labelDesviacionRodillaSubida';
            app.DesviacionRodillaSubidaLabel.Text = 'Desviación de la rodilla en la subida';
            app.DesviacionRodillaSubidaLabel2 = uilabel(app.GridLayout7_3);
            app.DesviacionRodillaSubidaLabel2.Tag = 'labelDesviacionRodillaSubida2';
            app.DesviacionRodillaSubidaLabel2.Text = '';
            app.GridLayout8_3 = uigridlayout(app.GridLayout3_8);
            app.IndiceVariacionRodillaSubidaLabel = uilabel(app.GridLayout8_3);
            app.IndiceVariacionRodillaSubidaLabel.Tag = 'labelIndiceVariacionRodillaSubida';
            app.IndiceVariacionRodillaSubidaLabel.Text = 'Indice de variación';
            app.GridLayout10_3 = uigridlayout(app.GridLayout8_3);
            app.NormalRodillaSubidaLabel = uilabel(app.GridLayout10_3);
            app.NormalRodillaSubidaLabel.Tag = 'labelNormalRodillaSubida';
            app.NormalRodillaSubidaLabel.Text = '  < 2.96º normal';
            app.LeveRodillaSubidaLabel = uilabel(app.GridLayout10_3);
            app.LeveRodillaSubidaLabel.Tag = 'labelLeveRodillaSubida';
            app.LeveRodillaSubidaLabel.Text = '2.96º - 12.22º leve ';
            app.GraveRodillaSubidaLabel = uilabel(app.GridLayout10_3);
            app.GraveRodillaSubidaLabel.Tag = 'labelGraveRodillaSubida';
            app.GraveRodillaSubidaLabel.Text = ' > 12.22º grave ';
            app.RodillaBTab = uitab(app.TabGroup);
            app.RodillaBTab.Title = 'RodillaB';
            app.GridLayout3_9 = uigridlayout(app.RodillaBTab);
            app.GridLayout7_4 = uigridlayout(app.GridLayout3_9);
            app.DesviacionRodillaBajadaLabel = uilabel(app.GridLayout7_4);
            app.DesviacionRodillaBajadaLabel.Tag = 'labelDesviacionRodillaBajada';
            app.DesviacionRodillaBajadaLabel.Text = 'Desviación de la rodilla en la bajada';
            app.DesviacionRodillaBajadaLabel2 = uilabel(app.GridLayout7_4);
            app.DesviacionRodillaBajadaLabel2.Tag = 'labelDesviacionRodillaBajada2';
            app.DesviacionRodillaBajadaLabel2.Text = '';
            app.GridLayout8_4 = uigridlayout(app.GridLayout3_9);
            app.IndiceVariacionRodillaBajadaLabel = uilabel(app.GridLayout8_4);
            app.IndiceVariacionRodillaBajadaLabel.Tag = 'labelIndiceVariacionRodillaBajada';
            app.IndiceVariacionRodillaBajadaLabel.Text = 'Indice de variación';
            app.GridLayout10_4 = uigridlayout(app.GridLayout8_4);
            app.NormalRodillaBajadaLabel = uilabel(app.GridLayout10_4);
            app.NormalRodillaBajadaLabel.Tag = 'labelNormalRodillaBajada';
            app.NormalRodillaBajadaLabel.Text = '  < 2.55º normal';
            app.LeveRodillaBajadaLabel = uilabel(app.GridLayout10_4);
            app.LeveRodillaBajadaLabel.Tag = 'labelLeveRodillaBajada';
            app.LeveRodillaBajadaLabel.Text = ' 2.55º - 11.18º leve ';
            app.GraveRodillaBajadaLabel = uilabel(app.GridLayout10_4);
            app.GraveRodillaBajadaLabel.Tag = 'labelGraveRodillaBajada';
            app.GraveRodillaBajadaLabel.Text = ' > 11.18º grave ';
            app.TobilloSTab = uitab(app.TabGroup);
            app.TobilloSTab.Title = 'TobilloS';
            app.GridLayout3_10 = uigridlayout(app.TobilloSTab);
            app.GridLayout7_5 = uigridlayout(app.GridLayout3_10);
            app.DesviacionTobilloSubidaLabel = uilabel(app.GridLayout7_5);
            app.DesviacionTobilloSubidaLabel.Tag = 'labelDesviacionTobilloSubida';
            app.DesviacionTobilloSubidaLabel.Text = 'Desviación del tobillo en la subida';
            app.DesviacionTobilloSubidaLabel2 = uilabel(app.GridLayout7_5);
            app.DesviacionTobilloSubidaLabel2.Tag = 'labelDesviacionTobilloSubida2';
            app.DesviacionTobilloSubidaLabel2.Text = '';
            app.GridLayout8_5 = uigridlayout(app.GridLayout3_10);
            app.IndiceVariacionTobilloSubidaLabel = uilabel(app.GridLayout8_5);
            app.IndiceVariacionTobilloSubidaLabel.Tag = 'labelIndiceVariacionTobilloSubida';
            app.IndiceVariacionTobilloSubidaLabel.Text = 'Indice de variación';
            app.GridLayout10_5 = uigridlayout(app.GridLayout8_5);
            app.NormalTobilloSubidaLabel = uilabel(app.GridLayout10_5);
            app.NormalTobilloSubidaLabel.Tag = 'labelNormalTobilloSubida';
            app.NormalTobilloSubidaLabel.Text = '  < 4.57º normal';
            app.LeveTobilloSubidaLabel = uilabel(app.GridLayout10_5);
            app.LeveTobilloSubidaLabel.Tag = 'labelLeveTobilloSubida';
            app.LeveTobilloSubidaLabel.Text = ' 4.57º - 8.70º leve ';
            app.GraveTobilloSubidaLabel = uilabel(app.GridLayout10_5);
            app.GraveTobilloSubidaLabel.Tag = 'labelGraveTobilloSubida';
            app.GraveTobilloSubidaLabel.Text = ' > 8.70º grave ';
            app.TobilloBTab = uitab(app.TabGroup);
            app.TobilloBTab.Title = 'TobilloB';
            app.GridLayout3_11 = uigridlayout(app.TobilloBTab);
            app.GridLayout7_6 = uigridlayout(app.GridLayout3_11);
            app.DesviacionTobilloBajadaLabel = uilabel(app.GridLayout7_6);
            app.DesviacionTobilloBajadaLabel.Tag = 'labelDesviacionTobilloBajada';
            app.DesviacionTobilloBajadaLabel.Text = 'Desviación del tobillo en la bajada';
            app.DesviacionTobilloBajadaLabel2 = uilabel(app.GridLayout7_6);
            app.DesviacionTobilloBajadaLabel2.Tag = 'labelDesviacionTobilloBajada2';
            app.DesviacionTobilloBajadaLabel2.Text = '';
            app.GridLayout8_6 = uigridlayout(app.GridLayout3_11);
            app.IndiceVariacionTobilloBajadaLabel = uilabel(app.GridLayout8_6);
            app.IndiceVariacionTobilloBajadaLabel.Tag = 'labelIndiceVariacionTobilloBajada';
            app.IndiceVariacionTobilloBajadaLabel.Text = 'Indice de variación';
            app.GridLayout10_6 = uigridlayout(app.GridLayout8_6);
            app.NormalTobilloBajadaLabel = uilabel(app.GridLayout10_6);
            app.NormalTobilloBajadaLabel.Tag = 'labelNormalTobilloBajada';
            app.NormalTobilloBajadaLabel.Text = '  < 3.73º normal';
            app.LeveTobilloBajadaLabel = uilabel(app.GridLayout10_6);
            app.LeveTobilloBajadaLabel.Tag = 'labelLeveTobilloBajada';
            app.LeveTobilloBajadaLabel.Text = ' 3.73º - 11.10º leve ';
            app.GraveTobilloBajadaLabel = uilabel(app.GridLayout10_6);
            app.GraveTobilloBajadaLabel.Tag = 'labelGraveTobilloBajada';
            app.GraveTobilloBajadaLabel.Text = ' > 11.10º grave ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_informacionCaptura_new2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end