classdef GUI_informe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        GridLayout8          matlab.ui.container.GridLayout
        GridLayout10_3       matlab.ui.container.GridLayout
        bajadaMAX_tobillo    matlab.ui.control.Label
        bajadaMIN_tobillo    matlab.ui.control.Label
        bajadaMAX_rodilla    matlab.ui.control.Label
        bajadaMIN_rodilla    matlab.ui.control.Label
        bajadaMAX_cadera     matlab.ui.control.Label
        bajadaMIN_cadera     matlab.ui.control.Label
        BajadaLabel          matlab.ui.control.Label
        GridLayout10_2       matlab.ui.container.GridLayout
        subidaMAX_tobillo    matlab.ui.control.Label
        subidaMIN_tobillo    matlab.ui.control.Label
        subidaMAX_rodilla    matlab.ui.control.Label
        subidaMIN_rodilla    matlab.ui.control.Label
        subidaMAX_cadera     matlab.ui.control.Label
        subidaMIN_cadera     matlab.ui.control.Label
        SubidaLabel          matlab.ui.control.Label
        GridLayout10         matlab.ui.container.GridLayout
        MAX_TobilloLabel     matlab.ui.control.Label
        MAX_RodillaLabel     matlab.ui.control.Label
        MAX_CaderaLabel      matlab.ui.control.Label
        MIN_TobilloLabel     matlab.ui.control.Label
        MIN_RodillaLabel     matlab.ui.control.Label
        MIN_CaderaLabel      matlab.ui.control.Label
        GridLayout9_2        matlab.ui.container.GridLayout
        TobilloLabel         matlab.ui.control.Label
        RodillaLabel         matlab.ui.control.Label
        CaderaLabel          matlab.ui.control.Label
        InformebiomecanicodelpacienteLabel  matlab.ui.control.Label
        ParametrosLabel      matlab.ui.control.Label
        GridLayout9          matlab.ui.container.GridLayout
        TiempoMaximo_Label1  matlab.ui.control.Label
        TiempoMinimo_Label1  matlab.ui.control.Label
        TiempoMAX_Label2     matlab.ui.control.Label
        TiempoMIN_Label2     matlab.ui.control.Label
        Hyperlink            matlab.ui.control.Hyperlink
        GridLayout3          matlab.ui.container.GridLayout
        BienvenidoaSRSLabel  matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout6          matlab.ui.container.GridLayout
        ApellidosLabel2      matlab.ui.control.Label
        ApellidosLabel       matlab.ui.control.Label
        FechaLabel2          matlab.ui.control.Label
        PesoLabel2           matlab.ui.control.Label
        EdadLabel2           matlab.ui.control.Label
        NombreLabel2         matlab.ui.control.Label
        FechaLabel           matlab.ui.control.Label
        PesoLabel            matlab.ui.control.Label
        EdadLabel            matlab.ui.control.Label
        NombreLabel          matlab.ui.control.Label
        GridLayout7          matlab.ui.container.GridLayout
        GrficasButton        matlab.ui.control.Button
        AvatarButton         matlab.ui.control.Button
        ImageLogo            matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global nombre apellidos edad peso;
            main;
            %load datos.mat nombre apellido edad peso;

            app.NombreLabel2.Text = strcat(nombre);
            app.ApellidosLabel2.Text = strcat(apellidos);
            app.EdadLabel2.Text = strcat(int2str(edad), " años");
            app.PesoLabel2.Text = strcat(num2str(peso) , "kg");

            formato = 'dd/mm/yy';
            app.FechaLabel2.Text = datestr(now,formato);

            %cadera
            
            app.subidaMAX_cadera.Text = strcat(num2str(max(cs)), "º");
            app.subidaMIN_cadera.Text = strcat(num2str(min(cs)), "º");
            app.bajadaMAX_cadera.Text = strcat(num2str(max(cb)), "º");
            app.bajadaMIN_cadera.Text = strcat(num2str(min(cb)), "º");
            %rodilla
            app.subidaMAX_rodilla.Text = strcat(num2str(max(rs)), "º");
            app.subidaMIN_rodilla.Text =strcat( num2str(min(rs)), "º");
            app.bajadaMAX_rodilla.Text = strcat(num2str(max(rb)), "º");
            app.bajadaMIN_rodilla.Text = strcat(num2str(min(rb)), "º");

            %tobillo
            app.subidaMAX_tobillo.Text = strcat(num2str(max(ts)), "º");
            app.subidaMIN_tobillo.Text = strcat(num2str(min(ts)), "º");
            app.bajadaMAX_tobillo.Text = strcat(num2str(max(tb)), "º");
            app.bajadaMIN_tobillo.Text = strcat(num2str(min(tb)), "º");

            %tiempo minimo y maximo
            app.TiempoMAX_Label2.Text = strcat(num2str(1.5), "s");
            app.TiempoMIN_Label2.Text = strcat(num2str(0.96), "s");


        end

        % Button pushed function: GrficasButton
        function GrficasButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_graficas;
            app.delete;
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_avatarAlfa;
            app.delete;
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            global nombre apellidos edad peso;
            time = datestr(clock,'YYYY/mm/dd HH:MM');
%           Esto para el nombre del fichero y que no se sobreescriban
            timeAux = datestr(clock,'YYYY_mm_dd HH_MM');
            datosPersonales1 = { 
                'Fecha de creación de fichero: ',time };
            datosPersonales2 = { 
                'Nombre: ', nombre...
                'Apellidos: ', apellidos...
                'Edad: ', strcat(int2str(edad), " años")...
                'Peso: ', strcat(int2str(peso), " kg")...
               };
           datosTiempo = {'Tiempo máximo: ', app.TiempoMAX_Label2.Text...
                'Tiempo mínimo: ', app.TiempoMIN_Label2.Text};
            datos = {
                 'Ángulo máximo cadera subida: ', app.subidaMAX_cadera.Text...
                 'Ángulo máximo cadera bajada: ',app.bajadaMAX_cadera.Text...
                 'Ángulo mínimo cadera subida: ',app.subidaMIN_cadera.Text...
                 'Ángulo mínimo cadera bajada: ',app.bajadaMIN_cadera.Text};
         datos2 = {
                 'Ángulo máximo rodilla subida: ' ,app.subidaMAX_rodilla.Text...
                 'Ángulo máximo rodilla bajada: ',app.bajadaMAX_rodilla.Text...
                 'Ángulo mínimo rodilla subida: ',app.subidaMIN_rodilla.Text...
                 'Ángulo mínimo rodilla bajada: ',app.bajadaMIN_rodilla.Text...
                };
          datos3 = {
                 'Ángulo máximo tobillo subida: ',app.subidaMAX_tobillo.Text...
                 'Ángulo máximo tobillo bajada: ',app.bajadaMAX_tobillo.Text...
                 'Ángulo mínimo tobillo subida: ',app.subidaMIN_tobillo.Text...
                 'Ángulo mínimo tobillo bajada: ',app.bajadaMIN_tobillo.Text...
                };

%           nombreArchivo = strcat("informe", nombre, apellido,".txt");
%           Se usa timeAux aqui porque el nombre del fichero no soporta algunos simbolos
          nombreArchivo = strcat("informe", nombre, apellidos,"(",timeAux,").txt");
          fileID = fopen(nombreArchivo,'w');
          fprintf(fileID,'\n');
          fprintf(fileID,'%s%s\n',datosPersonales1{:} );
          fprintf(fileID,'\n');
          fprintf(fileID,'%s%s\n',datosPersonales2{:} );
          fprintf(fileID,'\n');
          fprintf(fileID,'%s%s\n',datosTiempo{:} );
          fprintf(fileID,'\n');
          fprintf(fileID,'%s%s\n',datos{:} );
          fprintf(fileID,'\n');
          fprintf(fileID,'%s%s\n',datos2{:} );
          fprintf(fileID,'\n');
          fprintf(fileID,'%s%s\n',datos3{:} );
          fprintf(fileID,'\n');
          fclose(fileID);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'labelAngulosCadera';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'foto.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.AvatarButton = uibutton(app.GridLayout7, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.GrficasButton = uibutton(app.GridLayout7, 'push');
            app.GrficasButton.ButtonPushedFcn = createCallbackFcn(app, @GrficasButtonPushed, true);
            app.GrficasButton.Tag = 'buttonGráficas';
            app.GrficasButton.Text = 'Gráficas';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.NombreLabel = uilabel(app.GridLayout6);
            app.NombreLabel.Tag = 'labelNombre';
            app.NombreLabel.Text = 'Nombre:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesoLabel = uilabel(app.GridLayout6);
            app.PesoLabel.Tag = 'labelPeso';
            app.PesoLabel.Text = 'Peso:';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.NombreLabel2 = uilabel(app.GridLayout6);
            app.NombreLabel2.Tag = 'labelNombre2';
            app.NombreLabel2.Text = '';
            app.EdadLabel2 = uilabel(app.GridLayout6);
            app.EdadLabel2.Tag = 'labelEdad2';
            app.EdadLabel2.Text = '';
            app.PesoLabel2 = uilabel(app.GridLayout6);
            app.PesoLabel2.Tag = 'labelPeso2';
            app.PesoLabel2.Text = '';
            app.FechaLabel2 = uilabel(app.GridLayout6);
            app.FechaLabel2.Tag = 'labelFecha2';
            app.FechaLabel2.Text = '';
            app.ApellidosLabel = uilabel(app.GridLayout6);
            app.ApellidosLabel.Tag = 'labelApellidos';
            app.ApellidosLabel.Text = 'Apellidos:';
            app.ApellidosLabel2 = uilabel(app.GridLayout6);
            app.ApellidosLabel2.Tag = 'labelApellidos2';
            app.ApellidosLabel2.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Hyperlink = uihyperlink(app.GridLayout5);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Tag = 'labelGraficasentiemporeal';
            app.Hyperlink.Text = 'Descargar';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.TiempoMIN_Label2 = uilabel(app.GridLayout9);
            app.TiempoMIN_Label2.Tag = 'TiempoMIN_Label2';
            app.TiempoMIN_Label2.Text = '';
            app.TiempoMAX_Label2 = uilabel(app.GridLayout9);
            app.TiempoMAX_Label2.Tag = 'TiempoMAX_Label2';
            app.TiempoMAX_Label2.Text = '';
            app.TiempoMinimo_Label1 = uilabel(app.GridLayout9);
            app.TiempoMinimo_Label1.Tag = 'TiempoMinimo_Label1';
            app.TiempoMinimo_Label1.Text = 'Tiempo mínimo:';
            app.TiempoMaximo_Label1 = uilabel(app.GridLayout9);
            app.TiempoMaximo_Label1.Tag = 'TiempoMaximo_Label1';
            app.TiempoMaximo_Label1.Text = 'Tiempo máximo:';
            app.ParametrosLabel = uilabel(app.GridLayout8);
            app.ParametrosLabel.Tag = 'labelParametros';
            app.ParametrosLabel.Text = 'Parámetros';
            app.InformebiomecanicodelpacienteLabel = uilabel(app.GridLayout8);
            app.InformebiomecanicodelpacienteLabel.Tag = 'labelInformebiomecanicodelpaciente';
            app.InformebiomecanicodelpacienteLabel.Text = 'Informe biomecánico del paciente';
            app.GridLayout9_2 = uigridlayout(app.GridLayout8);
            app.CaderaLabel = uilabel(app.GridLayout9_2);
            app.CaderaLabel.Tag = 'labelCadera';
            app.CaderaLabel.Text = 'Cadera';
            app.RodillaLabel = uilabel(app.GridLayout9_2);
            app.RodillaLabel.Tag = 'labelRodilla';
            app.RodillaLabel.Text = 'Rodilla';
            app.TobilloLabel = uilabel(app.GridLayout9_2);
            app.TobilloLabel.Tag = 'labelTobillo';
            app.TobilloLabel.Text = 'Tobillo';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.MIN_CaderaLabel = uilabel(app.GridLayout10);
            app.MIN_CaderaLabel.Tag = 'MIN_CaderaLabel';
            app.MIN_CaderaLabel.Text = 'MIN';
            app.MIN_RodillaLabel = uilabel(app.GridLayout10);
            app.MIN_RodillaLabel.Tag = 'MIN_RodillaLabel';
            app.MIN_RodillaLabel.Text = 'MIN';
            app.MIN_TobilloLabel = uilabel(app.GridLayout10);
            app.MIN_TobilloLabel.Tag = 'MIN_TobilloLabel';
            app.MIN_TobilloLabel.Text = 'MIN';
            app.MAX_CaderaLabel = uilabel(app.GridLayout10);
            app.MAX_CaderaLabel.Tag = 'MAX_CaderaLabel';
            app.MAX_CaderaLabel.Text = 'MAX';
            app.MAX_RodillaLabel = uilabel(app.GridLayout10);
            app.MAX_RodillaLabel.Tag = 'MAX_RodillaLabel';
            app.MAX_RodillaLabel.Text = 'MAX';
            app.MAX_TobilloLabel = uilabel(app.GridLayout10);
            app.MAX_TobilloLabel.Tag = 'MAX_TobilloLabel';
            app.MAX_TobilloLabel.Text = 'MAX';
            app.GridLayout10_2 = uigridlayout(app.GridLayout8);
            app.SubidaLabel = uilabel(app.GridLayout10_2);
            app.SubidaLabel.Tag = 'labelSubida';
            app.SubidaLabel.Text = 'Subida';
            app.subidaMIN_cadera = uilabel(app.GridLayout10_2);
            app.subidaMIN_cadera.Tag = 'subidaMIN_cadera';
            app.subidaMIN_cadera.Text = '';
            app.subidaMAX_cadera = uilabel(app.GridLayout10_2);
            app.subidaMAX_cadera.Tag = 'subidaMAX_cadera';
            app.subidaMAX_cadera.Text = '';
            app.subidaMIN_rodilla = uilabel(app.GridLayout10_2);
            app.subidaMIN_rodilla.Tag = 'subidaMIN_rodilla';
            app.subidaMIN_rodilla.Text = '';
            app.subidaMAX_rodilla = uilabel(app.GridLayout10_2);
            app.subidaMAX_rodilla.Tag = 'subidaMAX_rodilla';
            app.subidaMAX_rodilla.Text = '';
            app.subidaMIN_tobillo = uilabel(app.GridLayout10_2);
            app.subidaMIN_tobillo.Tag = 'subidaMIN_tobillo';
            app.subidaMIN_tobillo.Text = '';
            app.subidaMAX_tobillo = uilabel(app.GridLayout10_2);
            app.subidaMAX_tobillo.Tag = 'subidaMAX_tobillo';
            app.subidaMAX_tobillo.Text = '';
            app.GridLayout10_3 = uigridlayout(app.GridLayout8);
            app.BajadaLabel = uilabel(app.GridLayout10_3);
            app.BajadaLabel.Tag = 'labelBajada';
            app.BajadaLabel.Text = 'Bajada';
            app.bajadaMIN_cadera = uilabel(app.GridLayout10_3);
            app.bajadaMIN_cadera.Tag = 'bajadaMIN_cadera';
            app.bajadaMIN_cadera.Text = '';
            app.bajadaMAX_cadera = uilabel(app.GridLayout10_3);
            app.bajadaMAX_cadera.Tag = 'bajadaMAX_cadera';
            app.bajadaMAX_cadera.Text = '';
            app.bajadaMIN_rodilla = uilabel(app.GridLayout10_3);
            app.bajadaMIN_rodilla.Tag = 'bajadaMIN_rodilla';
            app.bajadaMIN_rodilla.Text = '';
            app.bajadaMAX_rodilla = uilabel(app.GridLayout10_3);
            app.bajadaMAX_rodilla.Tag = 'bajadaMAX_rodilla';
            app.bajadaMAX_rodilla.Text = '';
            app.bajadaMIN_tobillo = uilabel(app.GridLayout10_3);
            app.bajadaMIN_tobillo.Tag = 'bajadaMIN_tobillo';
            app.bajadaMIN_tobillo.Text = '';
            app.bajadaMAX_tobillo = uilabel(app.GridLayout10_3);
            app.bajadaMAX_tobillo.Tag = 'bajadaMAX_tobillo';
            app.bajadaMAX_tobillo.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_informe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end