classdef GUI_informe_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout5               matlab.ui.container.GridLayout
        ngulosdeltobilloPanel     matlab.ui.container.Panel
        GridLayout11              matlab.ui.container.GridLayout
        Bajada_tobilloLabel       matlab.ui.control.Label
        Subida_tobilloLabel       matlab.ui.control.Label
        MIN_TobilloLabel          matlab.ui.control.Label
        MAX_TobilloLabel          matlab.ui.control.Label
        bajadaMAX_tobillo         matlab.ui.control.Label
        subidaMAX_tobillo         matlab.ui.control.Label
        bajadaMIN_tobillo         matlab.ui.control.Label
        subidaMIN_tobillo         matlab.ui.control.Label
        ngulosdelarodillaPanel    matlab.ui.container.Panel
        GridLayout10              matlab.ui.container.GridLayout
        bajadaMAX_rodilla         matlab.ui.control.Label
        subidaMAX_rodilla         matlab.ui.control.Label
        bajadaMIN_rodilla         matlab.ui.control.Label
        subidaMIN_rodilla         matlab.ui.control.Label
        MAX_RodillaLabel          matlab.ui.control.Label
        MIN_RodillaLabel          matlab.ui.control.Label
        Bajada_RodillaLabel       matlab.ui.control.Label
        Flexion_RodillaLabel      matlab.ui.control.Label
        ngulosdelacaderaPanel     matlab.ui.container.Panel
        GridLayout9               matlab.ui.container.GridLayout
        bajadaMAX_cadera          matlab.ui.control.Label
        subidaMAX_cadera          matlab.ui.control.Label
        bajadaMIN_cadera          matlab.ui.control.Label
        subidaMIN_cadera          matlab.ui.control.Label
        MAX_CaderaLabel           matlab.ui.control.Label
        MIN_CaderaLabel           matlab.ui.control.Label
        Bajada_CaderaLabel        matlab.ui.control.Label
        Subida_CaderaLabel        matlab.ui.control.Label
        Hyperlink                 matlab.ui.control.Hyperlink
        PrametrosPanel            matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        TiempoRealizado_Label2    matlab.ui.control.Label
        TiempoRealizado_Label1    matlab.ui.control.Label
        TiempoIdeal_Label2        matlab.ui.control.Label
        TiempoIdeal_Label1        matlab.ui.control.Label
        InformebiomecnicodelpacienteLabel  matlab.ui.control.Label
        GridLayout3               matlab.ui.container.GridLayout
        BienvenidoaSRSLabel       matlab.ui.control.Label
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout12              matlab.ui.container.GridLayout
        GridLayout13              matlab.ui.container.GridLayout
        InformacionCapturaButton  matlab.ui.control.Button
        ParametrosIdealesButton   matlab.ui.control.Button
        GridLayout4               matlab.ui.container.GridLayout
        GraficasButton            matlab.ui.control.Button
        AvatarButton              matlab.ui.control.Button
        GridLayout6               matlab.ui.container.GridLayout
        ApellidosLabel2           matlab.ui.control.Label
        ApellidosLabel            matlab.ui.control.Label
        FechaLabel2               matlab.ui.control.Label
        PesoLabel2                matlab.ui.control.Label
        EdadLabel2                matlab.ui.control.Label
        NombreLabel2              matlab.ui.control.Label
        FechaLabel                matlab.ui.control.Label
        PesoLabel                 matlab.ui.control.Label
        EdadLabel                 matlab.ui.control.Label
        NombreLabel               matlab.ui.control.Label
        ImageLogo                 matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global nombre apellidos edad peso tiempoRealizado ...
                   caderaSMIN caderaSMAX caderaBMIN caderaBMAX ...
                   rodillaSMIN rodillaSMAX rodillaBMIN rodillaBMAX ...
                   tobilloSMIN tobilloSMAX tobilloBMIN tobilloBMAX ...
                   s_cs s_rs s_ts s_cb s_rb s_tb;
            
            main;
            %load datos.mat nombre apellido edad peso;

            app.NombreLabel2.Text = strcat(nombre);
            app.ApellidosLabel2.Text = strcat(apellidos);
            app.EdadLabel2.Text = strcat(int2str(edad), " años");
            app.PesoLabel2.Text = strcat(num2str(peso) , "kg");

            formato = 'dd/mm/yy';
            app.FechaLabel2.Text = datestr(now,formato);

            %cadera
            app.subidaMAX_cadera.Text = strcat(num2str(max(s_cs)), "º");
            app.subidaMIN_cadera.Text = strcat(num2str(min(s_cs)), "º");
            app.bajadaMAX_cadera.Text = strcat(num2str(max(s_cb)), "º");
            app.bajadaMIN_cadera.Text = strcat(num2str(min(s_cb)), "º");

            %rodilla
            app.subidaMAX_rodilla.Text = strcat(num2str(max(s_rs)), "º");
            app.subidaMIN_rodilla.Text = strcat(num2str(min(s_rs)), "º");
            app.bajadaMAX_rodilla.Text = strcat(num2str(max(s_rb)), "º");
            app.bajadaMIN_rodilla.Text = strcat(num2str(min(s_rb)), "º");

            %tobillo
            app.subidaMAX_tobillo.Text = strcat(num2str(max(s_ts)), "º");
            app.subidaMIN_tobillo.Text = strcat(num2str(min(s_ts)), "º");
            app.bajadaMAX_tobillo.Text = strcat(num2str(max(s_tb)), "º");
            app.bajadaMIN_tobillo.Text = strcat(num2str(min(s_tb)), "º");

            % valores ideales tobillo
            "Parametros normales subida: 4.57º - 8.70º";
            "Parametros normales bajada: 3.73º - 11.10º";

            %tiempo minimo y maximo
            app.TiempoRealizado_Label2.Text = strcat(num2str(2.00), "s");
            app.TiempoIdeal_Label2.Text = strcat(num2str(1.23), "s");

        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            global nombre apellidos edad peso tiempoRealizado ...
                   caderaSMIN caderaSMAX caderaBMIN caderaBMAX ...
                   rodillaSMIN rodillaSMAX rodillaBMIN rodillaBMAX ...
                   tobilloSMIN tobilloSMAX tobilloBMIN tobilloBMAX ...
                   s_cs s_rs s_ts s_cb s_rb s_tb;

            time = datestr(clock,'YYYY/mm/dd HH:MM');

            % Esto para el nombre del fichero y que no se sobreescriban
            timeAux = datestr(clock,'YYYY_mm_dd HH_MM');

            datosPersonales1 = {'Fecha de creación de fichero: ',time};

            datosPersonales2 = {
                'Nombre: ', nombre...
                'Apellidos: ', apellidos...
                'Edad: ', strcat(int2str(edad), " años")...
                'Peso: ', strcat(int2str(peso), " kg")...
                };

            datosTiempo = {'Tiempo realizado: ', app.TiempoRealizado_Label2.Text...
                'Tiempo ideal: ', "1.23s"};

            datos = {
                'Ángulo máximo cadera subida: ', app.subidaMAX_cadera.Text...
                'Ángulo máximo cadera bajada: ',app.bajadaMAX_cadera.Text...
                'Ángulo mínimo cadera subida: ',app.subidaMIN_cadera.Text...
                'Ángulo mínimo cadera bajada: ',app.bajadaMIN_cadera.Text...
                };
            
            % valores ideales de cadera
            datos4 = "Parametros normales subida: 2.12º - 6.28º";
            datos7 = "Parametros normales bajada: 2.30º - 4.86º";

            datos2 = {
                'Ángulo máximo rodilla subida: ' ,app.subidaMAX_rodilla.Text...
                'Ángulo máximo rodilla bajada: ',app.bajadaMAX_rodilla.Text...
                'Ángulo mínimo rodilla subida: ',app.subidaMIN_rodilla.Text...
                'Ángulo mínimo rodilla bajada: ',app.bajadaMIN_rodilla.Text...
                };
            
            % valores ideales de rodilla
            datos5 = "Parametros normales subida: 2.96º - 12.22º";
            datos8 = "Parametros normales bajada: 2.55º - 11.18º";

            datos3 = {
                'Ángulo máximo tobillo subida: ',app.subidaMAX_tobillo.Text...
                'Ángulo máximo tobillo bajada: ',app.bajadaMAX_tobillo.Text...
                'Ángulo mínimo tobillo subida: ',app.subidaMIN_tobillo.Text...
                'Ángulo mínimo tobillo bajada: ',app.bajadaMIN_tobillo.Text...
                };

            % valores idales de tobillo
            datos6 = "Parametros normales subida: 4.57º - 8.70º";
            datos9 = "Parametros normales bajada: 3.73º - 11.10º";

            % nombreArchivo = strcat("informe", nombre, apellido,".txt");
            % Se usa timeAux aqui porque el nombre del fichero no soporta algunos simbolos
           
            nombreArchivo = strcat("informe", nombre, apellidos,"(",timeAux,").txt");
            fileID = fopen(nombreArchivo,'w');
            fprintf(fileID,'\n');
            fprintf(fileID,'%s%s\n',datosPersonales1{:} );
            fprintf(fileID,'\n');
            fprintf(fileID,'%s%s\n',datosPersonales2{:} );
            fprintf(fileID,'\n');
            fprintf(fileID,'%s%s\n',datosTiempo{:} );
            fprintf(fileID,'\n');
            fprintf(fileID,'%s%s\n',datos{:} );
            fprintf(fileID,'\n');

            fprintf(fileID,'%s%s\n',datos4{:} );
            fprintf(fileID,'\n');

            fprintf(fileID,'%s%s\n',datos7{:} );
            fprintf(fileID,'\n');
            fprintf(fileID,'\n');
            fprintf(fileID,'%s%s\n',datos2{:} );
            fprintf(fileID,'\n');
            fprintf(fileID,'%s%s\n',datos5{:} );
            fprintf(fileID,'\n');

            fprintf(fileID,'%s%s\n',datos8{:} );
            fprintf(fileID,'\n');
            fprintf(fileID,'\n');
            fprintf(fileID,'%s%s\n',datos3{:} );
            fprintf(fileID,'\n');

            fprintf(fileID,'%s%s\n',datos6{:} );
            fprintf(fileID,'\n');
            fprintf(fileID,'%s%s\n',datos9{:} );
            fprintf(fileID,'\n');
            fclose(fileID);

        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            global nombre apellidos edad peso tiempoRealizado ...
                   caderaSMIN caderaSMAX caderaBMIN caderaBMAX ...
                   rodillaSMIN rodillaSMAX rodillaBMIN rodillaBMAX ...
                   tobilloSMIN tobilloSMAX tobilloBMIN tobilloBMAX ...
                   s_cs s_rs s_ts s_cb s_rb s_tb;
            
            GUI_avatarAlfa_new;
            app.delete;
        end

        % Button pushed function: ParametrosIdealesButton
        function ParametrosIdealesButtonPushed(app, event)
            GUI_parametrosIdeales_new;
        end

        % Button pushed function: InformacionCapturaButton
        function InformacionCapturaButtonPushed(app, event)
            global caderaSMIN caderaSMAX caderaBMIN caderaBMAX ...
                   rodillaSMIN rodillaSMAX rodillaBMIN rodillaBMAX ...
                   tobilloSMIN tobilloSMAX tobilloBMIN tobilloBMAX ...
                   s_cs s_rs s_ts s_cb s_rb s_tb;
            
            GUI_informacionCaptura_new2;
        end

        % Button pushed function: GraficasButton
        function GraficasButtonPushed(app, event)
            global nombre apellidos edad peso tiempoRealizado ...
                   caderaSMIN caderaSMAX caderaBMIN caderaBMAX ...
                   rodillaSMIN rodillaSMAX rodillaBMIN rodillaBMAX ...
                   tobilloSMIN tobilloSMAX tobilloBMIN tobilloBMAX ...
                   s_cs s_rs s_ts s_cb s_rb s_tb;

            GUI_graficas_new4;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'foto.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.NombreLabel = uilabel(app.GridLayout6);
            app.NombreLabel.Tag = 'labelNombre';
            app.NombreLabel.Text = 'Nombre:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesoLabel = uilabel(app.GridLayout6);
            app.PesoLabel.Tag = 'labelPeso';
            app.PesoLabel.Text = 'Peso:';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.NombreLabel2 = uilabel(app.GridLayout6);
            app.NombreLabel2.Tag = 'labelNombre2';
            app.NombreLabel2.Text = '';
            app.EdadLabel2 = uilabel(app.GridLayout6);
            app.EdadLabel2.Tag = 'labelEdad2';
            app.EdadLabel2.Text = '';
            app.PesoLabel2 = uilabel(app.GridLayout6);
            app.PesoLabel2.Tag = 'labelPeso2';
            app.PesoLabel2.Text = '';
            app.FechaLabel2 = uilabel(app.GridLayout6);
            app.FechaLabel2.Tag = 'labelFecha2';
            app.FechaLabel2.Text = '';
            app.ApellidosLabel = uilabel(app.GridLayout6);
            app.ApellidosLabel.Tag = 'labelApellidos';
            app.ApellidosLabel.Text = 'Apellidos:';
            app.ApellidosLabel2 = uilabel(app.GridLayout6);
            app.ApellidosLabel2.Tag = 'labelApellidos2';
            app.ApellidosLabel2.Text = '';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.AvatarButton = uibutton(app.GridLayout4, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.GraficasButton = uibutton(app.GridLayout4, 'push');
            app.GraficasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficasButtonPushed, true);
            app.GraficasButton.Tag = 'buttonGraficas';
            app.GraficasButton.Text = 'Gráficas';
            app.GridLayout12 = uigridlayout(app.GridLayout2);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.ParametrosIdealesButton = uibutton(app.GridLayout13, 'push');
            app.ParametrosIdealesButton.ButtonPushedFcn = createCallbackFcn(app, @ParametrosIdealesButtonPushed, true);
            app.ParametrosIdealesButton.Tag = 'buttonParametrosIdeales';
            app.ParametrosIdealesButton.Text = 'Parámetros ideales';
            app.InformacionCapturaButton = uibutton(app.GridLayout13, 'push');
            app.InformacionCapturaButton.ButtonPushedFcn = createCallbackFcn(app, @InformacionCapturaButtonPushed, true);
            app.InformacionCapturaButton.Tag = 'buttonInformacionCaptura';
            app.InformacionCapturaButton.Text = 'Información de la captura';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.InformebiomecnicodelpacienteLabel = uilabel(app.GridLayout5);
            app.InformebiomecnicodelpacienteLabel.Tag = 'labelInforme';
            app.InformebiomecnicodelpacienteLabel.Text = 'Informe biomecánico del paciente';
            app.PrametrosPanel = uipanel(app.GridLayout5);
            app.PrametrosPanel.Title = 'Párametros';
            app.GridLayout8 = uigridlayout(app.PrametrosPanel);
            app.TiempoIdeal_Label1 = uilabel(app.GridLayout8);
            app.TiempoIdeal_Label1.Tag = 'TiempoIdeal_Label1';
            app.TiempoIdeal_Label1.Text = 'Tiempo ideal';
            app.TiempoIdeal_Label2 = uilabel(app.GridLayout8);
            app.TiempoIdeal_Label2.Tag = 'TiempoIdeal_Label2';
            app.TiempoIdeal_Label2.Text = '1.23 s';
            app.TiempoRealizado_Label1 = uilabel(app.GridLayout8);
            app.TiempoRealizado_Label1.Tag = 'TiempoRealizado_Label1';
            app.TiempoRealizado_Label1.Text = 'Tiempo realizado';
            app.TiempoRealizado_Label2 = uilabel(app.GridLayout8);
            app.TiempoRealizado_Label2.Tag = 'TiempoRealizado_Label2';
            app.TiempoRealizado_Label2.Text = '2 s';
            app.Hyperlink = uihyperlink(app.GridLayout5);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Tag = 'hypDescargar';
            app.Hyperlink.Text = 'Descargar';
            app.ngulosdelacaderaPanel = uipanel(app.GridLayout5);
            app.ngulosdelacaderaPanel.Title = 'Ángulos de la cadera';
            app.ngulosdelacaderaPanel.Tag = 'a_CaderaLabel';
            app.GridLayout9 = uigridlayout(app.ngulosdelacaderaPanel);
            app.Subida_CaderaLabel = uilabel(app.GridLayout9);
            app.Subida_CaderaLabel.Tag = 'Subida_CaderaLabel';
            app.Subida_CaderaLabel.Text = 'Subida';
            app.Bajada_CaderaLabel = uilabel(app.GridLayout9);
            app.Bajada_CaderaLabel.Tag = 'Bajada_CaderaLabel';
            app.Bajada_CaderaLabel.Text = 'Bajada';
            app.MIN_CaderaLabel = uilabel(app.GridLayout9);
            app.MIN_CaderaLabel.Tag = 'MIN_CaderaLabel';
            app.MIN_CaderaLabel.Text = 'MIN';
            app.MAX_CaderaLabel = uilabel(app.GridLayout9);
            app.MAX_CaderaLabel.Tag = 'MAX_CaderaLabel';
            app.MAX_CaderaLabel.Text = 'MAX';
            app.subidaMIN_cadera = uilabel(app.GridLayout9);
            app.subidaMIN_cadera.Tag = 'subidaMIN_cadera';
            app.subidaMIN_cadera.Text = '';
            app.bajadaMIN_cadera = uilabel(app.GridLayout9);
            app.bajadaMIN_cadera.Tag = 'bajadaMIN_cadera';
            app.bajadaMIN_cadera.Text = '';
            app.subidaMAX_cadera = uilabel(app.GridLayout9);
            app.subidaMAX_cadera.Tag = 'subidaMAX_cadera';
            app.subidaMAX_cadera.Text = '';
            app.bajadaMAX_cadera = uilabel(app.GridLayout9);
            app.bajadaMAX_cadera.Tag = 'bajadaMAX_cadera';
            app.bajadaMAX_cadera.Text = '';
            app.ngulosdelarodillaPanel = uipanel(app.GridLayout5);
            app.ngulosdelarodillaPanel.Title = 'Ángulos de la rodilla';
            app.ngulosdelarodillaPanel.Tag = 'a_RodillaLabel';
            app.GridLayout10 = uigridlayout(app.ngulosdelarodillaPanel);
            app.Flexion_RodillaLabel = uilabel(app.GridLayout10);
            app.Flexion_RodillaLabel.Tag = 'Subida_RodillaLabel';
            app.Flexion_RodillaLabel.Text = 'Subida';
            app.Bajada_RodillaLabel = uilabel(app.GridLayout10);
            app.Bajada_RodillaLabel.Tag = 'Bajada_RodillaLabel';
            app.Bajada_RodillaLabel.Text = 'Bajada';
            app.MIN_RodillaLabel = uilabel(app.GridLayout10);
            app.MIN_RodillaLabel.Tag = 'MIN_RodillaLabel';
            app.MIN_RodillaLabel.Text = 'MIN';
            app.MAX_RodillaLabel = uilabel(app.GridLayout10);
            app.MAX_RodillaLabel.Tag = 'MAX_RodillaLabel';
            app.MAX_RodillaLabel.Text = 'MAX';
            app.subidaMIN_rodilla = uilabel(app.GridLayout10);
            app.subidaMIN_rodilla.Tag = 'subidaMIN_rodilla';
            app.subidaMIN_rodilla.Text = '';
            app.bajadaMIN_rodilla = uilabel(app.GridLayout10);
            app.bajadaMIN_rodilla.Tag = 'bajadaMIN_rodilla';
            app.bajadaMIN_rodilla.Text = '';
            app.subidaMAX_rodilla = uilabel(app.GridLayout10);
            app.subidaMAX_rodilla.Tag = 'subidaMAX_rodilla';
            app.subidaMAX_rodilla.Text = '';
            app.bajadaMAX_rodilla = uilabel(app.GridLayout10);
            app.bajadaMAX_rodilla.Tag = 'bajadaMAX_rodilla';
            app.bajadaMAX_rodilla.Text = '';
            app.ngulosdeltobilloPanel = uipanel(app.GridLayout5);
            app.ngulosdeltobilloPanel.Title = 'Ángulos del tobillo';
            app.ngulosdeltobilloPanel.Tag = 'a_TobilloLabel';
            app.GridLayout11 = uigridlayout(app.ngulosdeltobilloPanel);
            app.subidaMIN_tobillo = uilabel(app.GridLayout11);
            app.subidaMIN_tobillo.Tag = 'subidaMIN_tobillo';
            app.subidaMIN_tobillo.Text = '';
            app.bajadaMIN_tobillo = uilabel(app.GridLayout11);
            app.bajadaMIN_tobillo.Tag = 'bajadaMIN_tobillo';
            app.bajadaMIN_tobillo.Text = '';
            app.subidaMAX_tobillo = uilabel(app.GridLayout11);
            app.subidaMAX_tobillo.Tag = 'subidaMAX_tobillo';
            app.subidaMAX_tobillo.Text = '';
            app.bajadaMAX_tobillo = uilabel(app.GridLayout11);
            app.bajadaMAX_tobillo.Tag = 'bajadaMAX_tobillo';
            app.bajadaMAX_tobillo.Text = '';
            app.MAX_TobilloLabel = uilabel(app.GridLayout11);
            app.MAX_TobilloLabel.Tag = 'MAX_TobilloLabel';
            app.MAX_TobilloLabel.Text = 'MAX';
            app.MIN_TobilloLabel = uilabel(app.GridLayout11);
            app.MIN_TobilloLabel.Tag = 'MIN_TobilloLabel';
            app.MIN_TobilloLabel.Text = 'MIN';
            app.Subida_tobilloLabel = uilabel(app.GridLayout11);
            app.Subida_tobilloLabel.Tag = 'Subida_tobilloLabel';
            app.Subida_tobilloLabel.Text = 'Subida';
            app.Bajada_tobilloLabel = uilabel(app.GridLayout11);
            app.Bajada_tobilloLabel.Tag = 'Bajada_tobilloLabel';
            app.Bajada_tobilloLabel.Text = 'Bajada ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_informe_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end