classdef GUI_inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        AvisoLabel               matlab.ui.control.Label
        GridLayout5              matlab.ui.container.GridLayout
        PesokgEditField          matlab.ui.control.NumericEditField
        PesokgEditFieldLabel     matlab.ui.control.Label
        EdadEditField            matlab.ui.control.NumericEditField
        EdadEditFieldLabel       matlab.ui.control.Label
        ApellidosEditField       matlab.ui.control.EditField
        ApellidosEditFieldLabel  matlab.ui.control.Label
        NombreEditField          matlab.ui.control.EditField
        NombreEditFieldLabel     matlab.ui.control.Label
        FechaDatePicker          matlab.ui.control.DatePicker
        FechaDatePickerLabel     matlab.ui.control.Label
        GridLayout3              matlab.ui.container.GridLayout
        PorfavorrellenelossiguientescamposLabel  matlab.ui.control.Label
        BienvenidoaSRSLabel      matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        InformeButton            matlab.ui.control.Button
        GrficasButton            matlab.ui.control.Button
        AvatarButton             matlab.ui.control.Button
        LabelInfo                matlab.ui.control.Label
        ImageLogo                matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrficasButton
        function GrficasButtonPushed(app, event)
%             fecha = app.FechaDatePicker.Value;

            nombre = app.NombreEditField.Value;
            apellidos = app.ApellidosEditField.Value;
            edad = app.EdadEditField.Value;
            peso = app.PesokgEditField.Value;
          
            if isempty(nombre) || isempty(apellidos)
               app.AvisoLabel.Text = 'Para continuar asegúrese de que todos los campos están rellenos';
            elseif edad == 0 
                app.AvisoLabel.Text = 'Asegúrese de que la edad introducida no es 0';
            elseif peso == 0
               app.AvisoLabel.Text = 'Asegúrese de que el peso introducido no es 0';
            else    
               GUI_graficas;
               app.delete;
            end
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            nombre = app.NombreEditField.Value;
            apellidos = app.ApellidosEditField.Value;
            edad = app.EdadEditField.Value;
            peso = app.PesokgEditField.Value;

            if isempty(nombre) || isempty(apellidos)
               app.AvisoLabel.Text = 'Para continuar asegúrese de que todos los campos están rellenos';
            elseif edad == 0 
                app.AvisoLabel.Text = 'Asegúrese de que la edad introducida no es 0';
            elseif peso == 0
               app.AvisoLabel.Text = 'Asegúrese de que el peso introducido no es 0';
            else
               GUI_avatarAlfa;
               app.delete;
            end
        end

        % Button pushed function: InformeButton
        function InformeButtonPushed(app, event)
            nombre = app.NombreEditField.Value;
            apellidos = app.ApellidosEditField.Value;
            edad = app.EdadEditField.Value;
            peso = app.PesokgEditField.Value;

            if isempty(nombre) || isempty(apellidos)
               app.AvisoLabel.Text = 'Para continuar asegúrese de que todos los campos están rellenos';
            elseif edad == 0 
                app.AvisoLabel.Text = 'Asegúrese de que la edad introducida no es 0';
            elseif peso == 0
               app.AvisoLabel.Text = 'Asegúrese de que el peso introducido no es 0';
            else
               GUI_informe;
               app.delete;
            end
        end

        % Value changed function: FechaDatePicker
        function FechaDatePickerValueChanged(app, event)
            fecha1 = app.FechaDatePicker.PreviousValue;
            fecha2 = app.FechaDatePicker.Value;

            if fecha1 == fecha2
               app.AvisoLabel.Text = 'Por favor, seleccione una fecha';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'foto.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LabelInfo = uilabel(app.GridLayout2);
            app.LabelInfo.Tag = 'labelInfo';
            app.LabelInfo.Text = 'Tras rellenar las campos, elija una opción de las que se muestran en el menú inferior';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.AvatarButton = uibutton(app.GridLayout6, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.GrficasButton = uibutton(app.GridLayout6, 'push');
            app.GrficasButton.ButtonPushedFcn = createCallbackFcn(app, @GrficasButtonPushed, true);
            app.GrficasButton.Tag = 'buttonGráficas';
            app.GrficasButton.Text = 'Gráficas';
            app.InformeButton = uibutton(app.GridLayout6, 'push');
            app.InformeButton.ButtonPushedFcn = createCallbackFcn(app, @InformeButtonPushed, true);
            app.InformeButton.Tag = 'buttonInforme';
            app.InformeButton.Text = 'Informe';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.PorfavorrellenelossiguientescamposLabel = uilabel(app.GridLayout3);
            app.PorfavorrellenelossiguientescamposLabel.Tag = 'labelCampos';
            app.PorfavorrellenelossiguientescamposLabel.Text = 'Por favor, rellene los siguientes campos';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.FechaDatePickerLabel = uilabel(app.GridLayout5);
            app.FechaDatePickerLabel.Tag = 'labelFecha';
            app.FechaDatePickerLabel.Text = 'Fecha';
            app.FechaDatePicker = uidatepicker(app.GridLayout5);
            app.FechaDatePicker.ValueChangedFcn = createCallbackFcn(app, @FechaDatePickerValueChanged, true);
            app.NombreEditFieldLabel = uilabel(app.GridLayout5);
            app.NombreEditFieldLabel.Tag = 'labelNombre';
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.GridLayout5, 'text');
            app.NombreEditField.Tag = 'textFieldNombre';
            app.ApellidosEditFieldLabel = uilabel(app.GridLayout5);
            app.ApellidosEditFieldLabel.Tag = 'labelApellidos';
            app.ApellidosEditFieldLabel.Text = 'Apellidos';
            app.ApellidosEditField = uieditfield(app.GridLayout5, 'text');
            app.ApellidosEditField.Tag = 'textFieldApellidos';
            app.EdadEditFieldLabel = uilabel(app.GridLayout5);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.GridLayout5, 'numeric');
            app.EdadEditField.ValueDisplayFormat = '%.0f';
            app.EdadEditField.Tag = 'NumericFieldEdad';
            app.PesokgEditFieldLabel = uilabel(app.GridLayout5);
            app.PesokgEditFieldLabel.Text = 'Peso (kg)';
            app.PesokgEditField = uieditfield(app.GridLayout5, 'numeric');
            app.PesokgEditField.Tag = 'NumericFieldPeso';
            app.AvisoLabel = uilabel(app.GridLayout4);
            app.AvisoLabel.Tag = 'labelAviso';
            app.AvisoLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end