classdef VentanaVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VIDEO2Button                  matlab.ui.control.Button
        nguloEditField                matlab.ui.control.NumericEditField
        EditField_2Label_2            matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        TotalFramesEditField          matlab.ui.control.NumericEditField
        TotalFramesEditFieldLabel_2   matlab.ui.control.Label
        PorcentajeG                   matlab.ui.control.NumericEditField
        EditField_3Label              matlab.ui.control.Label
        MissedFramesG                 matlab.ui.control.NumericEditField
        MissedFramesEditField_3Label  matlab.ui.control.Label
        PorcentajeB                   matlab.ui.control.NumericEditField
        EditField_2Label              matlab.ui.control.Label
        MissedFramesB                 matlab.ui.control.NumericEditField
        MissedFramesEditField_2Label  matlab.ui.control.Label
        PorcentajeR                   matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        MissedFramesR                 matlab.ui.control.NumericEditField
        MissedFramesEditFieldLabel    matlab.ui.control.Label
        VIDEO1Button                  matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VIDEO1Button
        function Go(app, event)
            %Frame
            v=VideoReader('MTD2.mp4');
            j=0;
            k=0;
            l=0;
            for i=1:v.NumFrames
             app.EditField.Value=(i/v.NumFrames)*100;
             app.TotalFramesEditField.Value=i;
                tic;
        
            f=readFrame(v);
            imshow(f,'parent',app.UIAxes);
            se=strel('disk',20);

            %RED
            [BR,maskedRImage]=createMaskR(f);
            BR_ao=bwareaopen(BR,1000);
            BR_ce=imclose(BR_ao,se);
            %mR=bsxfun(@times, f, cast(BR_ce,'like',f));
            imshow(BR_ce,'parent',app.UIAxes_2);
            %sum(BR_ce(fila izq:fila dcha,col arriba:col abajo),'all')
            framesR=sum(BR_ce,'all');

            if(framesR==0)
                j=j+1;
                app.MissedFramesR.Value=j;
            end
            app.PorcentajeR.Value=(app.MissedFramesR.Value/i)*100;

            %BLUE
            [BB,maskedBImage]=createMaskB(f);
            BB_ao=bwareaopen(BB,400);
            BB_ce=imclose(BB_ao,se);
            %mB=bsxfun(@times, f, cast(BB_ce,'like',f));
            imshow(BB_ce,'parent',app.UIAxes_4);
            framesB=sum(BB_ce,'all');

            if(framesB==0)
                k=k+1;
                app.MissedFramesB.Value=k;
            end
            app.PorcentajeB.Value=(app.MissedFramesB.Value/i)*100;

             %GREEN
            [BG,maskedGImage]=createMaskG(f);
            BG_ce=imclose(BG,se);
            %mG=bsxfun(@times, f, cast(BG_ce,'like',f));
            imshow(BG_ce,'parent',app.UIAxes_3);
            framesG=sum(BG_ce,'all');

            if(framesG==0)
                l=l+1;
                app.MissedFramesG.Value=l;
            end
            app.PorcentajeG.Value=(app.MissedFramesG.Value/i)*100;

%             allMasks= imfuse(imfuse(BR_ce,BB_ce),BG_ce);
%             allMasksColor=bsxfun(@times,f,cast(allMasks,'like',f));
            mAll= BR_ce | BB_ce | BG_ce;
            mAllc=bsxfun(@times,f,cast(mAll,'like',f));
            imshow(mAllc,'parent',app.UIAxes2);
            drawnow;
            
            %Centroides
            [labeledImage, numberOfRegions] = bwlabel(mAll);
            if numberOfRegions >= 1
                    stats =regionprops(labeledImage, 'BoundingBox', 'Centroid');
                    for r=1 : numberOfRegions
                        thisCentroid=stats(r).Centroid;
                        hold(app.UIAxes2,'on')
                        plot(app.UIAxes2,thisCentroid(1),thisCentroid(2),'r+','MarkerSize',10,'LineWidth',2);
                        hold(app.UIAxes2,'off')
                        m(r,:)=stats(r).Centroid;
                    end
            end

            if numberOfRegions==3
                [mi, in]=min(m(:,2));
                cR=m(in,:);
                m(in,:)=[];
                [mi, in]=min(m(:,2));
                cB=m(in,:);
                m(in,:)=[];
                cG=m;

                p=polyfit([cR(1) cB(1)],[cR(2) cB(2)],1);
                p2=polyfit([cB(1) cG(1)],[cB(2) cG(2)],1);

                vect1=[1 p(1)];
                vect2=[1 p2(1)];

                dp=dot(vect1,vect2);
                length1=sqrt(sum(vect1.^2));
                length2=sqrt(sum(vect2.^2));
                angle = 180-acos(dp/(length1*length2))*180/pi;
                app.nguloEditField.Value=angle;

            else
                app.nguloEditField.Value=0;
            end
            t=toc;
            pause(1-t);
            
            end
        end

        % Button pushed function: VIDEO2Button
        function VIDEO2(app, event)
           %Frame
            v=VideoReader('VIDEO CON MARCADORES.mp4');
            j=0;
            k=0;
            l=0;
            for i=1:v.NumFrames
             app.EditField.Value=(i/v.NumFrames)*100;
             app.TotalFramesEditField.Value=i;
                tic;
        
            f=readFrame(v);
            imshow(f,'parent',app.UIAxes);
            se=strel('disk',20);

            %YELLOW
            [BY,maskedYImage]=createMaskVPY(f);
            BY_ao=bwareaopen(BY,200);
            BY_ce=imclose(BY_ao,se);
            %mR=bsxfun(@times, f, cast(BR_ce,'like',f));
            imshow(BY_ce,'parent',app.UIAxes_2);
            %sum(BR_ce(fila izq:fila dcha,col arriba:col abajo),'all')
            framesY=sum(BY_ce,'all');

            if(framesY==0)
                j=j+1;
                app.MissedFramesR.Value=j;
            end
            app.PorcentajeR.Value=(app.MissedFramesR.Value/i)*100;

            %GREEN
            [BG,maskedGImage]=createMaskVPG(f);
            %BB_ao=bwareaopen(BB,400);
            BG_ce=imclose(BG,se);
            %mB=bsxfun(@times, f, cast(BB_ce,'like',f));
            imshow(BG_ce,'parent',app.UIAxes_4);
            framesG=sum(BG_ce,'all');

            if(framesG==0)
                k=k+1;
                app.MissedFramesB.Value=k;
            end
            app.PorcentajeB.Value=(app.MissedFramesB.Value/i)*100;

             %GREEN
            [BR,maskedRImage]=createMaskVPR(f);
            BR_ce=imclose(BR,se);
            %mG=bsxfun(@times, f, cast(BG_ce,'like',f));
            imshow(BR_ce,'parent',app.UIAxes_3);
            framesR=sum(BR_ce,'all');

            if(framesR==0)
                l=l+1;
                app.MissedFramesG.Value=l;
            end
            app.PorcentajeG.Value=(app.MissedFramesG.Value/i)*100;

%             allMasks= imfuse(imfuse(BR_ce,BB_ce),BG_ce);
%             allMasksColor=bsxfun(@times,f,cast(allMasks,'like',f));
            mAll= BR_ce | BY_ce | BG_ce;
            mAllc=bsxfun(@times,f,cast(mAll,'like',f));
            imshow(mAllc,'parent',app.UIAxes2);
            drawnow;
            
            %Centroides
            [labeledImage, numberOfRegions] = bwlabel(mAll);
            if numberOfRegions >= 1
                    stats =regionprops(labeledImage, 'BoundingBox', 'Centroid');
                    for r=1 : numberOfRegions
                        thisCentroid=stats(r).Centroid;
                        hold(app.UIAxes2,'on')
                        plot(app.UIAxes2,thisCentroid(1),thisCentroid(2),'r+','MarkerSize',10,'LineWidth',2);
                        hold(app.UIAxes2,'off')
                        m(r,:)=stats(r).Centroid;
                    end
            end

            if numberOfRegions==3
                [mi, in]=min(m(:,2));
                cR=m(in,:);
                m(in,:)=[];
                [mi, in]=min(m(:,2));
                cB=m(in,:);
                m(in,:)=[];
                cG=m;

                p=polyfit([cR(1) cB(1)],[cR(2) cB(2)],1);
                p2=polyfit([cB(1) cG(1)],[cB(2) cG(2)],1);

                vect1=[1 p(1)];
                vect2=[1 p2(1)];

                dp=dot(vect1,vect2);
                length1=sqrt(sum(vect1.^2));
                length2=sqrt(sum(vect2.^2));
                angle = 180-acos(dp/(length1*length2))*180/pi;
                app.nguloEditField.Value=angle;

            else
                app.nguloEditField.Value=0;
            end
            t=toc;
            pause(1-t);
            
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'RED')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'GREEN')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'BLUE')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'ALL MASKS')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.VIDEO1Button = uibutton(app.UIFigure, 'push');
            app.VIDEO1Button.ButtonPushedFcn = createCallbackFcn(app, @Go, true);
            app.VIDEO1Button.Text = 'VIDEO1';
            app.MissedFramesEditFieldLabel = uilabel(app.UIFigure);
            app.MissedFramesEditFieldLabel.Text = 'Missed Frames';
            app.MissedFramesR = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '%';
            app.PorcentajeR = uieditfield(app.UIFigure, 'numeric');
            app.MissedFramesEditField_2Label = uilabel(app.UIFigure);
            app.MissedFramesEditField_2Label.Text = 'Missed Frames';
            app.MissedFramesB = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '%';
            app.PorcentajeB = uieditfield(app.UIFigure, 'numeric');
            app.MissedFramesEditField_3Label = uilabel(app.UIFigure);
            app.MissedFramesEditField_3Label.Text = 'Missed Frames';
            app.MissedFramesG = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '%';
            app.PorcentajeG = uieditfield(app.UIFigure, 'numeric');
            app.TotalFramesEditFieldLabel_2 = uilabel(app.UIFigure);
            app.TotalFramesEditFieldLabel_2.Text = 'Total Frames';
            app.TotalFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label_2 = uilabel(app.UIFigure);
            app.EditField_2Label_2.Text = 'Ángulo';
            app.nguloEditField = uieditfield(app.UIFigure, 'numeric');
            app.VIDEO2Button = uibutton(app.UIFigure, 'push');
            app.VIDEO2Button.ButtonPushedFcn = createCallbackFcn(app, @VIDEO2, true);
            app.VIDEO2Button.Text = 'VIDEO2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanaVideo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end